classdef Robot_Kinematigi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GENERALTab                      matlab.ui.container.Tab
        TablesPanel                     matlab.ui.container.Panel
        RollPitchYawOrientationLabel_2  matlab.ui.control.Label
        B_UITable_RollPitchYaw_2        matlab.ui.control.Table
        JakobianLabel                   matlab.ui.control.Label
        PositionLabel                   matlab.ui.control.Label
        OrientationLabel                matlab.ui.control.Label
        A_UITable_Jakobiyen             matlab.ui.control.Table
        A_UITable_Konum                 matlab.ui.control.Table
        A_UITable_Oryantasyon           matlab.ui.control.Table
        SliderForAxisPanel              matlab.ui.container.Panel
        EditField_Max_Axes5             matlab.ui.control.NumericEditField
        MaxLabel_5                      matlab.ui.control.Label
        EditField_Min_Axes5             matlab.ui.control.NumericEditField
        MinLabel_5                      matlab.ui.control.Label
        MinLabel_6                      matlab.ui.control.Label
        EditField_Min_Axes6             matlab.ui.control.NumericEditField
        MaxLabel_6                      matlab.ui.control.Label
        EditField_Max_Axes6             matlab.ui.control.NumericEditField
        EditField_Max_Axes7             matlab.ui.control.NumericEditField
        MaxLabel_7                      matlab.ui.control.Label
        EditField_Min_Axes7             matlab.ui.control.NumericEditField
        MinLabel_7                      matlab.ui.control.Label
        MinLabel_8                      matlab.ui.control.Label
        EditField_Min_Axes8             matlab.ui.control.NumericEditField
        MaxLabel_8                      matlab.ui.control.Label
        EditField_Max_Axes8             matlab.ui.control.NumericEditField
        EditField_Max_Axes4             matlab.ui.control.NumericEditField
        MaxLabel_4                      matlab.ui.control.Label
        EditField_Min_Axes4             matlab.ui.control.NumericEditField
        MinLabel_4                      matlab.ui.control.Label
        MinLabel_3                      matlab.ui.control.Label
        EditField_Min_Axes3             matlab.ui.control.NumericEditField
        MaxLabel_3                      matlab.ui.control.Label
        EditField_Max_Axes3             matlab.ui.control.NumericEditField
        EditField_Max_Axes2             matlab.ui.control.NumericEditField
        MaxLabel_2                      matlab.ui.control.Label
        EditField_Min_Axes2             matlab.ui.control.NumericEditField
        MinLabel_2                      matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        EditField_Max_Axes1             matlab.ui.control.NumericEditField
        EditField_Min_Axes1             matlab.ui.control.NumericEditField
        A_Theta1_Slider1                matlab.ui.control.Slider
        Axis1Label                      matlab.ui.control.Label
        A_Theta8_Slider8                matlab.ui.control.Slider
        Axis8Label                      matlab.ui.control.Label
        A_Theta7_Slider7                matlab.ui.control.Slider
        Axis7Label                      matlab.ui.control.Label
        A_Theta6_Slider6                matlab.ui.control.Slider
        Axis6Label                      matlab.ui.control.Label
        A_Theta5_Slider5                matlab.ui.control.Slider
        Axis5Label                      matlab.ui.control.Label
        A_Theta4_Slider4                matlab.ui.control.Slider
        Axis4Label                      matlab.ui.control.Label
        A_Theta3_Slider3                matlab.ui.control.Slider
        Axis3Label                      matlab.ui.control.Label
        A_Theta2_Slider2                matlab.ui.control.Slider
        Axis2Label                      matlab.ui.control.Label
        JointTypePanel                  matlab.ui.container.Panel
        A_Eighth_Axis                   matlab.ui.control.NumericEditField
        A_Seventh_Axis                  matlab.ui.control.NumericEditField
        A_Sixth_Axis                    matlab.ui.control.NumericEditField
        A_Fifth_Axis                    matlab.ui.control.NumericEditField
        A_Fourth_Axis                   matlab.ui.control.NumericEditField
        A_Third_Axis                    matlab.ui.control.NumericEditField
        A_Second_Axis                   matlab.ui.control.NumericEditField
        A_First_Axis                    matlab.ui.control.NumericEditField
        A_EklemTipiButton8              matlab.ui.container.ButtonGroup
        A_NoneButton_8                  matlab.ui.control.RadioButton
        A_PrismatikButton_8             matlab.ui.control.RadioButton
        A_RevoluteButton_8              matlab.ui.control.RadioButton
        A_EklemTipiButton7              matlab.ui.container.ButtonGroup
        A_NoneButton_7                  matlab.ui.control.RadioButton
        A_PrismatikButton_7             matlab.ui.control.RadioButton
        A_RevoluteButton_7              matlab.ui.control.RadioButton
        A_EklemTipiButton6              matlab.ui.container.ButtonGroup
        A_NoneButton_6                  matlab.ui.control.RadioButton
        A_PrismatikButton_6             matlab.ui.control.RadioButton
        A_RevoluteButton_6              matlab.ui.control.RadioButton
        A_EklemTipiButton5              matlab.ui.container.ButtonGroup
        A_NoneButton_5                  matlab.ui.control.RadioButton
        A_PrismatikButton_5             matlab.ui.control.RadioButton
        A_RevoluteButton_5              matlab.ui.control.RadioButton
        A_EklemTipiButton4              matlab.ui.container.ButtonGroup
        A_NoneButton_4                  matlab.ui.control.RadioButton
        A_PrismatikButton_4             matlab.ui.control.RadioButton
        A_RevoluteButton_4              matlab.ui.control.RadioButton
        A_EklemTipiButton3              matlab.ui.container.ButtonGroup
        A_NoneButton_3                  matlab.ui.control.RadioButton
        A_PrismatikButton_3             matlab.ui.control.RadioButton
        A_RevoluteButton_3              matlab.ui.control.RadioButton
        A_EklemTipiButton2              matlab.ui.container.ButtonGroup
        A_NoneButton_2                  matlab.ui.control.RadioButton
        A_PrismatikButton_2             matlab.ui.control.RadioButton
        A_RevoluteButton_2              matlab.ui.control.RadioButton
        A_EklemTipiButton1              matlab.ui.container.ButtonGroup
        A_NoneButton_1                  matlab.ui.control.RadioButton
        A_PrismatikButton_1             matlab.ui.control.RadioButton
        A_RevoluteButton_1              matlab.ui.control.RadioButton
        DHTablePanel                    matlab.ui.container.Panel
        A_DRAWButton                    matlab.ui.control.Button
        A_Delete_Button                 matlab.ui.control.Button
        A_CALCULATEButton               matlab.ui.control.Button
        A_TextArea_theta8               matlab.ui.control.NumericEditField
        A_TextArea_d8                   matlab.ui.control.NumericEditField
        A_TextArea_a7                   matlab.ui.control.NumericEditField
        A_TextArea_alpha7               matlab.ui.control.NumericEditField
        A_TextArea_theta7               matlab.ui.control.NumericEditField
        A_TextArea_d7                   matlab.ui.control.NumericEditField
        A_TextArea_a6                   matlab.ui.control.NumericEditField
        A_TextArea_alpha6               matlab.ui.control.NumericEditField
        A_TextArea_theta6               matlab.ui.control.NumericEditField
        A_TextArea_d6                   matlab.ui.control.NumericEditField
        A_TextArea_a5                   matlab.ui.control.NumericEditField
        A_TextArea_alpha5               matlab.ui.control.NumericEditField
        A_TextArea_theta5               matlab.ui.control.NumericEditField
        A_TextArea_d5                   matlab.ui.control.NumericEditField
        A_TextArea_a4                   matlab.ui.control.NumericEditField
        A_TextArea_alpha4               matlab.ui.control.NumericEditField
        A_TextArea_theta4               matlab.ui.control.NumericEditField
        A_TextArea_d4                   matlab.ui.control.NumericEditField
        A_TextArea_a3                   matlab.ui.control.NumericEditField
        A_TextArea_alpha3               matlab.ui.control.NumericEditField
        A_TextArea_theta3               matlab.ui.control.NumericEditField
        A_TextArea_d3                   matlab.ui.control.NumericEditField
        A_TextArea_a2                   matlab.ui.control.NumericEditField
        A_TextArea_alpha2               matlab.ui.control.NumericEditField
        A_TextArea_theta2               matlab.ui.control.NumericEditField
        A_TextArea_d2                   matlab.ui.control.NumericEditField
        A_TextArea_a1                   matlab.ui.control.NumericEditField
        A_TextArea_alpha1               matlab.ui.control.NumericEditField
        A_TextArea_theta1               matlab.ui.control.NumericEditField
        A_TextArea_d1                   matlab.ui.control.NumericEditField
        A_TextArea_a0                   matlab.ui.control.NumericEditField
        A_TextArea_alpha0               matlab.ui.control.NumericEditField
        A_Label                         matlab.ui.control.Label
        A_i1Label                       matlab.ui.control.Label
        A_aLabel                        matlab.ui.control.Label
        A_i1Label_2                     matlab.ui.control.Label
        A_dLabel                        matlab.ui.control.Label
        A_iLabel                        matlab.ui.control.Label
        A_iLabel_2                      matlab.ui.control.Label
        A_Label_2                       matlab.ui.control.Label
        InverseKinematicsPanel_2        matlab.ui.container.Panel
        A_Ters_Kinematik                matlab.ui.control.Table
        TargetZLabel_2                  matlab.ui.control.Label
        TargetYLabel_2                  matlab.ui.control.Label
        TargetXLabel_2                  matlab.ui.control.Label
        YawLabel_2                      matlab.ui.control.Label
        PitchLabel_2                    matlab.ui.control.Label
        RollLabel_2                     matlab.ui.control.Label
        A_TersHesaplaButtonGroup        matlab.ui.container.ButtonGroup
        A_NewtonRapsonButton            matlab.ui.control.RadioButton
        A_FabrikButton                  matlab.ui.control.RadioButton
        A_YKonumuEditField              matlab.ui.control.NumericEditField
        A_XKonumuEditField              matlab.ui.control.NumericEditField
        A_ZKonumuEditField              matlab.ui.control.NumericEditField
        A_UploadButton_3                matlab.ui.control.Button
        A_EditField_C_Yaw               matlab.ui.control.NumericEditField
        A_EditField_B_Pitch             matlab.ui.control.NumericEditField
        A_EditField_A_Roll              matlab.ui.control.NumericEditField
        A_UIAxes1                       matlab.ui.control.UIAxes
        KUKA_KR8_R1420Tab               matlab.ui.container.Tab
        TablesGroupPanel                matlab.ui.container.Panel
        RollPitchYawOrientationLabel    matlab.ui.control.Label
        B_UITable_RollPitchYaw          matlab.ui.control.Table
        ForwardKinematicsLabel          matlab.ui.control.Label
        B_UITable_ForwardKinematic      matlab.ui.control.Table
        PositionLabel_2                 matlab.ui.control.Label
        OrientationLabel_2              matlab.ui.control.Label
        B_UITable2_Location             matlab.ui.control.Table
        B_UITable_Oryantasyon           matlab.ui.control.Table
        InverseKinematicsPanel          matlab.ui.container.Panel
        InverseKinematicLabel           matlab.ui.control.Label
        B_Ters_Kinematik                matlab.ui.control.Table
        B_TersHesaplaButtonGroup        matlab.ui.container.ButtonGroup
        B_NewtonRapsonButton            matlab.ui.control.RadioButton
        B_FabrikButton                  matlab.ui.control.RadioButton
        TargetZLabel                    matlab.ui.control.Label
        TargetYLabel                    matlab.ui.control.Label
        TargetXLabel                    matlab.ui.control.Label
        B_YKonumuEditField              matlab.ui.control.NumericEditField
        B_XKonumuEditField              matlab.ui.control.NumericEditField
        B_ZKonumuEditField              matlab.ui.control.NumericEditField
        YawLabel                        matlab.ui.control.Label
        PitchLabel                      matlab.ui.control.Label
        RollLabel                       matlab.ui.control.Label
        B_EditField_C_Yaw               matlab.ui.control.NumericEditField
        B_EditField_B_Pitch             matlab.ui.control.NumericEditField
        B_EditField_A_Roll              matlab.ui.control.NumericEditField
        SliderForAxisPanel_2            matlab.ui.container.Panel
        AxisLabel_3                     matlab.ui.control.Label
        AxisLabel_6                     matlab.ui.control.Label
        AxisLabel_2                     matlab.ui.control.Label
        AxisLabel_5                     matlab.ui.control.Label
        AxisLabel_4                     matlab.ui.control.Label
        AxisLabel                       matlab.ui.control.Label
        B_Theta4_Slider4                matlab.ui.control.Slider
        B_Theta5_Slider5                matlab.ui.control.Slider
        B_Theta3_Slider3                matlab.ui.control.Slider
        B_Theta6_Slider6                matlab.ui.control.Slider
        B_Theta1_Slider1                matlab.ui.control.Slider
        B_Theta2_Slider2                matlab.ui.control.Slider
        DHTablePanel_2                  matlab.ui.container.Panel
        B_UploadButton_2                matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        B_EnableConrolCheckBox          matlab.ui.control.CheckBox
        B_DELETEButton                  matlab.ui.control.Button
        B_TextArea_theta6               matlab.ui.control.NumericEditField
        B_TextArea_theta5               matlab.ui.control.NumericEditField
        B_TextArea_theta4               matlab.ui.control.NumericEditField
        B_TextArea_theta3               matlab.ui.control.NumericEditField
        B_TextArea_theta2               matlab.ui.control.NumericEditField
        B_TextArea_theta1               matlab.ui.control.NumericEditField
        B_TextArea_d6                   matlab.ui.control.NumericEditField
        B_TextArea_d5                   matlab.ui.control.NumericEditField
        B_TextArea_d4                   matlab.ui.control.NumericEditField
        B_TextArea_d3                   matlab.ui.control.NumericEditField
        B_TextArea_d2                   matlab.ui.control.NumericEditField
        B_TextArea_d1                   matlab.ui.control.NumericEditField
        B_TextArea_a5                   matlab.ui.control.NumericEditField
        B_TextArea_a4                   matlab.ui.control.NumericEditField
        B_TextArea_a3                   matlab.ui.control.NumericEditField
        B_TextArea_a2                   matlab.ui.control.NumericEditField
        B_TextArea_a1                   matlab.ui.control.NumericEditField
        B_TextArea_a0                   matlab.ui.control.NumericEditField
        B_TextArea_alpha5               matlab.ui.control.NumericEditField
        B_TextArea_alpha4               matlab.ui.control.NumericEditField
        B_TextArea_alpha3               matlab.ui.control.NumericEditField
        B_TextArea_alpha2               matlab.ui.control.NumericEditField
        B_TextArea_alpha1               matlab.ui.control.NumericEditField
        B_TextArea_alpha0               matlab.ui.control.NumericEditField
        iLabel_4                        matlab.ui.control.Label
        iLabel_3                        matlab.ui.control.Label
        i1Label_4                       matlab.ui.control.Label
        i1Label_3                       matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        dLabel_2                        matlab.ui.control.Label
        aLabel_2                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        B_UploadButton                  matlab.ui.control.Button
        B_UIAxes1                       matlab.ui.control.UIAxes
        ASetiHesaplamaTab               matlab.ui.container.Tab
        KLabel                          matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        C_K3                            matlab.ui.control.NumericEditField
        C_K2                            matlab.ui.control.NumericEditField
        C_K1                            matlab.ui.control.NumericEditField
        C_Teta_Text                     matlab.ui.control.NumericEditField
        GammaLabel                      matlab.ui.control.Label
        BetaLabel_2                     matlab.ui.control.Label
        AlphaLabel_2                    matlab.ui.control.Label
        AngleValueLabel_2               matlab.ui.control.Label
        Gama_Cikti                      matlab.ui.control.NumericEditField
        Beta_Cikti                      matlab.ui.control.NumericEditField
        Alpha_Cikti                     matlab.ui.control.NumericEditField
        CalculateAnglePanel             matlab.ui.container.Panel
        C_UITable_2                     matlab.ui.control.Table
        C_Aci_Button                    matlab.ui.control.Button
        RotationMatrixLabel             matlab.ui.control.Label
        A3                              matlab.ui.control.NumericEditField
        B3                              matlab.ui.control.NumericEditField
        G3                              matlab.ui.control.NumericEditField
        G2                              matlab.ui.control.NumericEditField
        B2                              matlab.ui.control.NumericEditField
        A2                              matlab.ui.control.NumericEditField
        A1                              matlab.ui.control.NumericEditField
        B1                              matlab.ui.control.NumericEditField
        G1                              matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        CalculateRotationPanel          matlab.ui.container.Panel
        AngleValueLabel                 matlab.ui.control.Label
        C_UITable_Rotasyon              matlab.ui.control.Table
        C_Rotasyon_Button               matlab.ui.control.Button
        GamaLabel                       matlab.ui.control.Label
        BetaLabel                       matlab.ui.control.Label
        AlphaLabel                      matlab.ui.control.Label
        G                               matlab.ui.control.NumericEditField
        B                               matlab.ui.control.NumericEditField
        A                               matlab.ui.control.NumericEditField
        C_ButtonGroup                   matlab.ui.container.ButtonGroup
        EULERLabel                      matlab.ui.control.Label
        RollPitchYawLabel               matlab.ui.control.Label
        C_SabitRPY_ZYZ                  matlab.ui.control.RadioButton
        C_SabitRPY_ZXZ                  matlab.ui.control.RadioButton
        C_SabitRPY_YZY                  matlab.ui.control.RadioButton
        C_SabitRPY_YXY                  matlab.ui.control.RadioButton
        C_SabitRPY_XZX                  matlab.ui.control.RadioButton
        C_SabitRPY_XYX                  matlab.ui.control.RadioButton
        C_SabitRPY_ZYX                  matlab.ui.control.RadioButton
        C_SabitRPY_ZXY                  matlab.ui.control.RadioButton
        C_SabitRPY_YZX                  matlab.ui.control.RadioButton
        C_SabitRPY_XZY                  matlab.ui.control.RadioButton
        C_SabitRPY_YXZ                  matlab.ui.control.RadioButton
        C_SabitRPY_XYZ                  matlab.ui.control.RadioButton
        C_Hareketli_ZYZ                 matlab.ui.control.RadioButton
        C_Hareketli_ZXZ                 matlab.ui.control.RadioButton
        C_Hareketli_YZY                 matlab.ui.control.RadioButton
        C_Hareketli_YXY                 matlab.ui.control.RadioButton
        C_Hareketli_XZX                 matlab.ui.control.RadioButton
        C_Hareketli_XYX                 matlab.ui.control.RadioButton
        C_Hareketli_ZYX                 matlab.ui.control.RadioButton
        C_Hareketli_ZXY                 matlab.ui.control.RadioButton
        C_Hareketli_YZX                 matlab.ui.control.RadioButton
        C_Hareketli_XZY                 matlab.ui.control.RadioButton
        C_Hareketli_YXZ                 matlab.ui.control.RadioButton
        C_Hareketli_XYZ                 matlab.ui.control.RadioButton
    end

    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                %Startup Function Eklenebilir.
                app.B_TextArea_alpha0.Enable = "off";
                app.B_TextArea_alpha1.Enable = "off";
                app.B_TextArea_alpha2.Enable = "off";
                app.B_TextArea_alpha3.Enable = "off";
                app.B_TextArea_alpha4.Enable = "off";
                app.B_TextArea_alpha5.Enable = "off";

                app.B_TextArea_a0.Enable ="off";
                app.B_TextArea_a1.Enable ="off";
                app.B_TextArea_a2.Enable ="off";
                app.B_TextArea_a3.Enable ="off";
                app.B_TextArea_a4.Enable ="off";
                app.B_TextArea_a5.Enable ="off";

                app.B_TextArea_d1.Enable = "off";
                app.B_TextArea_d2.Enable = "off";
                app.B_TextArea_d3.Enable = "off";
                app.B_TextArea_d4.Enable = "off";
                app.B_TextArea_d5.Enable = "off";
                app.B_TextArea_d6.Enable = "off";

                app.EditField_Min_Axes1.Value = app.A_Theta1_Slider1.Limits(1,1);
                app.EditField_Max_Axes1.Value = app.A_Theta1_Slider1.Limits(1,2);
                app.EditField_Min_Axes2.Value = app.A_Theta2_Slider2.Limits(1,1);
                app.EditField_Max_Axes2.Value = app.A_Theta2_Slider2.Limits(1,2);
                app.EditField_Min_Axes3.Value = app.A_Theta3_Slider3.Limits(1,1);
                app.EditField_Max_Axes3.Value = app.A_Theta3_Slider3.Limits(1,2);
                app.EditField_Min_Axes4.Value = app.A_Theta4_Slider4.Limits(1,1);
                app.EditField_Max_Axes4.Value = app.A_Theta4_Slider4.Limits(1,2);
                app.EditField_Min_Axes5.Value = app.A_Theta5_Slider5.Limits(1,1);
                app.EditField_Max_Axes5.Value = app.A_Theta5_Slider5.Limits(1,2);
                app.EditField_Min_Axes6.Value = app.A_Theta6_Slider6.Limits(1,1);
                app.EditField_Max_Axes6.Value = app.A_Theta6_Slider6.Limits(1,2);
                app.EditField_Min_Axes7.Value = app.A_Theta7_Slider7.Limits(1,1);
                app.EditField_Max_Axes7.Value = app.A_Theta7_Slider7.Limits(1,2);
                app.EditField_Min_Axes8.Value = app.A_Theta8_Slider8.Limits(1,1);
                app.EditField_Max_Axes8.Value = app.A_Theta8_Slider8.Limits(1,2);
        end

        % Button pushed function: A_CALCULATEButton
        function A_CALCULATEButtonPushed(app, event)
%             alpha0 = str2double(app.A_TextArea_alpha0.Value)*pi/180;
%             alpha1 = str2double(app.A_TextArea_alpha1.Value)*pi/180;
%             alpha2 = str2double(app.A_TextArea_alpha2.Value)*pi/180;
%             alpha3 = str2double(app.A_TextArea_alpha3.Value)*pi/180;
%             alpha4 = str2double(app.A_TextArea_alpha4.Value)*pi/180;
%             alpha5 = str2double(app.A_TextArea_alpha5.Value)*pi/180;
%             alpha6 = str2double(app.A_TextArea_alpha6.Value)*pi/180;
%             alpha7 = str2double(app.A_TextArea_alpha7.Value)*pi/180;
%             
%             a0 = str2double(app.A_TextArea_a0.Value);
%             a1 = str2double(app.A_TextArea_a1.Value);
%             a2 = str2double(app.A_TextArea_a2.Value);
%             a3 = str2double(app.A_TextArea_a3.Value);
%             a4 = str2double(app.A_TextArea_a4.Value);
%             a5 = str2double(app.A_TextArea_a5.Value);
%             a6 = str2double(app.A_TextArea_a6.Value);
%             a7 = str2double(app.A_TextArea_a7.Value);
%             
%             
%             d1 = str2double(app.A_TextArea_d1.Value);
%             d2 = str2double(app.A_TextArea_d2.Value);
%             d3 = str2double(app.A_TextArea_d3.Value);
%             d4 = str2double(app.A_TextArea_d4.Value);
%             d5 = str2double(app.A_TextArea_d5.Value);
%             d6 = str2double(app.A_TextArea_d6.Value);
%             d7 = str2double(app.A_TextArea_d7.Value);
%             d8 = str2double(app.A_TextArea_d8.Value);
%  
%             theta1 = str2double(app.A_TextArea_theta1.Value)*pi/180;
%             theta2 = str2double(app.A_TextArea_theta2.Value)*pi/180;
%             theta3 = str2double(app.A_TextArea_theta3.Value)*pi/180;
%             theta4 = str2double(app.A_TextArea_theta4.Value)*pi/180;
%             theta5 = str2double(app.A_TextArea_theta5.Value)*pi/180;
%             theta6 = str2double(app.A_TextArea_theta6.Value)*pi/180;
%             theta7 = str2double(app.A_TextArea_theta7.Value)*pi/180;
%             theta8 = str2double(app.A_TextArea_theta8.Value)*pi/180;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Atamalar

            
            A_alpha0 = app.A_TextArea_alpha0.Value*pi/180; %degreetoradian
            A_alpha1 = app.A_TextArea_alpha1.Value*pi/180;
            A_alpha2 = app.A_TextArea_alpha2.Value*pi/180;
            A_alpha3 = app.A_TextArea_alpha3.Value*pi/180;
            A_alpha4 = app.A_TextArea_alpha4.Value*pi/180;
            A_alpha5 = app.A_TextArea_alpha5.Value*pi/180;
            A_alpha6 = app.A_TextArea_alpha6.Value*pi/180;
            A_alpha7 = app.A_TextArea_alpha7.Value*pi/180;

            A_a0 = app.A_TextArea_a0.Value;
            A_a1 = app.A_TextArea_a1.Value;
            A_a2 = app.A_TextArea_a2.Value;
            A_a3 = app.A_TextArea_a3.Value;
            A_a4 = app.A_TextArea_a4.Value;
            A_a5 = app.A_TextArea_a5.Value;
            A_a6 = app.A_TextArea_a6.Value;
            A_a7 = app.A_TextArea_a7.Value;

            A_d1 = app.A_TextArea_d1.Value;
            A_d2 = app.A_TextArea_d2.Value;
            A_d3 = app.A_TextArea_d3.Value;
            A_d4 = app.A_TextArea_d4.Value;
            A_d5 = app.A_TextArea_d5.Value;
            A_d6 = app.A_TextArea_d6.Value;
            A_d7 = app.A_TextArea_d7.Value;
            A_d8 = app.A_TextArea_d8.Value;

            A_theta1 = app.A_TextArea_theta1.Value*pi/180;
            A_theta2 = app.A_TextArea_theta2.Value*pi/180;
            A_theta3 = app.A_TextArea_theta3.Value*pi/180;
            A_theta4 = app.A_TextArea_theta4.Value*pi/180;
            A_theta5 = app.A_TextArea_theta5.Value*pi/180;
            A_theta6 = app.A_TextArea_theta6.Value*pi/180;
            A_theta7 = app.A_TextArea_theta7.Value*pi/180;
            A_theta8 = app.A_TextArea_theta8.Value*pi/180;
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %DH tablosu       alpha        a          d         theta
            
            A_dhtablosu = [   A_alpha0       A_a0	      A_d1        A_theta1;
                              A_alpha1       A_a1	      A_d2        A_theta2;
                              A_alpha2       A_a2	      A_d3        A_theta3;
                              A_alpha3       A_a3	      A_d4        A_theta4;
                              A_alpha4       A_a4	      A_d5        A_theta5;
                              A_alpha5       A_a5	      A_d6        A_theta6;
                              A_alpha6       A_a6	      A_d7        A_theta7;
                              A_alpha7       A_a7	      A_d8        A_theta8   ];
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            %Transformasyon matrislerinin hesaplanması
            
            for i=1:8
                A_Transformasyonmatris{i}=[        cos(A_dhtablosu(i,4))                         -sin(A_dhtablosu(i,4))                           0                               A_dhtablosu(i,2);
                    sin(A_dhtablosu(i,4))*cos(A_dhtablosu(i,1))      cos(A_dhtablosu(i,4))*cos(A_dhtablosu(i,1))      -sin(A_dhtablosu(i,1))         -sin(A_dhtablosu(i,1))*A_dhtablosu(i,3);
                    sin(A_dhtablosu(i,4))*sin(A_dhtablosu(i,1))      cos(A_dhtablosu(i,4))*sin(A_dhtablosu(i,1))       cos(A_dhtablosu(i,1))          cos(A_dhtablosu(i,1))*A_dhtablosu(i,3);
                    0                                            0                                    0                                      1];
            end
            
            A_Transformasyonmatris{1};
            A_Transformasyonmatris{2};
            A_Transformasyonmatris{3};
            A_Transformasyonmatris{4};
            A_Transformasyonmatris{5};
            A_Transformasyonmatris{6};
            A_Transformasyonmatris{7};
            A_Transformasyonmatris{8};
            %Transformasyon matrislerinin hesaplanması
            A_Transformasyonmatris01 = A_Transformasyonmatris{1};
            A_Transformasyonmatris02 = A_Transformasyonmatris{1}*A_Transformasyonmatris{2};
            A_Transformasyonmatris03 = A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3};
            A_Transformasyonmatris04 = A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4};
            A_Transformasyonmatris05 = A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5};
            A_Transformasyonmatris06 = A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5}*A_Transformasyonmatris{6};
            A_Transformasyonmatris07 = A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5}*A_Transformasyonmatris{6}*A_Transformasyonmatris{7};

            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            %İleri kinematik transformasyon matrisi
            
            A_Transformasyonmatris08=A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5}*A_Transformasyonmatris{6}*A_Transformasyonmatris{7}*A_Transformasyonmatris{8};
            
%             app.A_UITable_Oryantasyon.Data = Transformasyonmatris08; % 08T değerinin tabloya yazılması


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%             if app.A_RevoluteButton_1 == 1
%                 Jakobiyen01 = [Jakobiyen01; Transformasyonmatris01(1:3,3)];
%             elseif app.A_PrismatikButton_1 == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0]; 
%             else
%                 Jakobiyen01 = [0;0;0;0;0;0];
%             end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%           %Jakobiyen Hesaplama
            A_z11 = [0;0;1];
            A_z22 = [0;0;1];
            A_z33 = [0;0;1];
            A_z44 = [0;0;1];
            A_z55 = [0;0;1];
            A_z66 = [0;0;1];
            A_z77 = [0;0;1];
            A_z88 = [0;0;1];
            
            
            A_Rotasyon1 = A_Transformasyonmatris01(1:3,1:3);
            A_Rotasyon2 = A_Transformasyonmatris02(1:3,1:3);
            A_Rotasyon3 = A_Transformasyonmatris03(1:3,1:3);
            A_Rotasyon4 = A_Transformasyonmatris04(1:3,1:3);
            A_Rotasyon5 = A_Transformasyonmatris05(1:3,1:3);
            A_Rotasyon6 = A_Transformasyonmatris06(1:3,1:3);
            A_Rotasyon7 = A_Transformasyonmatris07(1:3,1:3);
            A_Rotasyon8 = A_Transformasyonmatris08(1:3,1:3);
            
            
            A_z01 = A_Rotasyon1 * A_z11;
            A_z02 = A_Rotasyon2 * A_z22;
            A_z03 = A_Rotasyon3 * A_z33;
            A_z04 = A_Rotasyon4 * A_z44;
            A_z05 = A_Rotasyon5 * A_z55;
            A_z06 = A_Rotasyon6 * A_z66;
            A_z07 = A_Rotasyon7 * A_z77;
            A_z08 = A_Rotasyon8 * A_z88;
            
            A_p01 = A_Transformasyonmatris01(1:3,4);
            A_p02 = A_Transformasyonmatris02(1:3,4);
            A_p03 = A_Transformasyonmatris03(1:3,4);
            A_p04 = A_Transformasyonmatris04(1:3,4);
            A_p05 = A_Transformasyonmatris05(1:3,4);
            A_p06 = A_Transformasyonmatris06(1:3,4);
            A_p07 = A_Transformasyonmatris07(1:3,4);
            A_p08 = A_Transformasyonmatris08(1:3,4);
            
            A_p08_01 = A_p08 - A_p01;
            A_p08_02 = A_p08 - A_p02;
            A_p08_03 = A_p08 - A_p03;
            A_p08_04 = A_p08 - A_p04;
            A_p08_05 = A_p08 - A_p05;
            A_p08_06 = A_p08 - A_p06;
            A_p08_07 = A_p08 - A_p07;
            A_p08_08 = A_p08 - A_p08;
            
            
%             Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                            z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                            z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1)];
            
%             Jakobiyen02 = [z02(2,1)*p08_02(3,1) - z02(3,1)*p08_02(2,1);
%                            z02(3,1)*p08_02(1,1) - z02(1,1)*p08_02(3,1);
%                            z02(1,1)*p08_02(2,1) - z02(2,1)*p08_02(1,1)];
            
%             Jakobiyen03 = [z03(2,1)*p08_03(3,1) - z03(3,1)*p08_03(2,1);
%                            z03(3,1)*p08_03(1,1) - z03(1,1)*p08_03(3,1);
%                            z03(1,1)*p08_03(2,1) - z03(2,1)*p08_03(1,1)];
            
%             Jakobiyen04 = [z04(2,1)*p08_04(3,1) - z04(3,1)*p08_04(2,1);
%                            z04(3,1)*p08_04(1,1) - z04(1,1)*p08_04(3,1);
%                            z04(1,1)*p08_04(2,1) - z04(2,1)*p08_04(1,1)];
           
%             Jakobiyen05 = [z05(2,1)*p08_05(3,1) - z05(3,1)*p08_05(2,1);
%                            z05(3,1)*p08_05(1,1) - z05(1,1)*p08_05(3,1);
%                            z05(1,1)*p08_05(2,1) - z05(2,1)*p08_05(1,1)];
            
%             Jakobiyen06 = [z06(2,1)*p08_06(3,1) - z06(3,1)*p08_06(2,1);
%                            z06(3,1)*p08_06(1,1) - z06(1,1)*p08_06(3,1);
%                            z06(1,1)*p08_06(2,1) - z06(2,1)*p08_06(1,1)];
            
%             Jakobiyen07 = [z07(2,1)*p08_07(3,1) - z07(3,1)*p08_07(2,1);
%                            z07(3,1)*p08_07(1,1) - z07(1,1)*p08_07(3,1);
%                            z07(1,1)*p08_07(2,1) - z07(2,1)*p08_07(1,1)];
            
%             Jakobiyen08 = [z08(2,1)*p08_08(3,1) - z08(3,1)*p08_08(2,1);
%                            z08(3,1)*p08_08(1,1) - z08(1,1)*p08_08(3,1);
%                            z08(1,1)*p08_08(2,1) - z08(2,1)*p08_08(1,1)];


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
             %First Axis
            if app.A_First_Axis.Value == 1
                A_Jakobiyen01 = [A_z01(2,1)*A_p08_01(3,1) - A_z01(3,1)*A_p08_01(2,1);
                                 A_z01(3,1)*A_p08_01(1,1) - A_z01(1,1)*A_p08_01(3,1);
                                 A_z01(1,1)*A_p08_01(2,1) - A_z01(2,1)*A_p08_01(1,1); A_Rotasyon1(1:3,3)];
                
            elseif app.A_First_Axis.Value == 2
                A_Jakobiyen01 = [A_Transformasyonmatris01(1:3,3);0;0;0];
                
            elseif app.A_First_Axis.Value == 3
                A_Jakobiyen01 = [0;0;0;0;0;0];
                
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Second Axis
            if app.A_Second_Axis.Value == 1
                A_Jakobiyen02 = [A_z02(2,1)*A_p08_02(3,1) - A_z02(3,1)*A_p08_02(2,1);
                                 A_z02(3,1)*A_p08_02(1,1) - A_z02(1,1)*A_p08_02(3,1);
                                 A_z02(1,1)*A_p08_02(2,1) - A_z02(2,1)*A_p08_02(1,1);A_Rotasyon2(1:3,3)];
            elseif app.A_Second_Axis.Value == 2
                A_Jakobiyen02 = [A_Transformasyonmatris02(1:3,3);0;0;0];
            elseif app.A_Second_Axis.Value == 3
                A_Jakobiyen02 = [0;0;0;0;0;0];
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Third Axis
            if app.A_Third_Axis.Value == 1
                A_Jakobiyen03 = [A_z03(2,1)*A_p08_03(3,1) - A_z03(3,1)*A_p08_03(2,1);
                                 A_z03(3,1)*A_p08_03(1,1) - A_z03(1,1)*A_p08_03(3,1);
                                 A_z03(1,1)*A_p08_03(2,1) - A_z03(2,1)*A_p08_03(1,1);A_Rotasyon3(1:3,3)];
            elseif app.A_Third_Axis.Value == 2
                A_Jakobiyen03 = [A_Transformasyonmatris03(1:3,3);0;0;0];
            elseif app.A_Third_Axis.Value == 3
                A_Jakobiyen03 = [0;0;0;0;0;0];
            end            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Fourth Axis
            if app.A_Fourth_Axis.Value == 1
                A_Jakobiyen04 = [A_z04(2,1)*A_p08_04(3,1) - A_z04(3,1)*A_p08_04(2,1);
                                 A_z04(3,1)*A_p08_04(1,1) - A_z04(1,1)*A_p08_04(3,1);
                                 A_z04(1,1)*A_p08_04(2,1) - A_z04(2,1)*A_p08_04(1,1);A_Rotasyon4(1:3,3)];
            elseif app.A_Fourth_Axis.Value == 2
                A_Jakobiyen04 = [A_Transformasyonmatris04(1:3,3);0;0;0];
            elseif app.A_Fourth_Axis.Value == 3
                A_Jakobiyen04 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Fifth Axis
            if app.A_Fifth_Axis.Value == 1
                A_Jakobiyen05 = [A_z05(2,1)*A_p08_05(3,1) - A_z05(3,1)*A_p08_05(2,1);
                                 A_z05(3,1)*A_p08_05(1,1) - A_z05(1,1)*A_p08_05(3,1);
                                 A_z05(1,1)*A_p08_05(2,1) - A_z05(2,1)*A_p08_05(1,1);A_Rotasyon5(1:3,3)];
            elseif app.A_Fifth_Axis.Value == 2
                A_Jakobiyen05 = [A_Transformasyonmatris05(1:3,3);0;0;0];
            elseif app.A_Fifth_Axis.Value == 3
                A_Jakobiyen05 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Sixth_Axis 
            if app.A_Sixth_Axis.Value == 1
                A_Jakobiyen06 = [A_z06(2,1)*A_p08_06(3,1) - A_z06(3,1)*A_p08_06(2,1);
                                 A_z06(3,1)*A_p08_06(1,1) - A_z06(1,1)*A_p08_06(3,1);
                                 A_z06(1,1)*A_p08_06(2,1) - A_z06(2,1)*A_p08_06(1,1);A_Rotasyon6(1:3,3)];
            elseif app.A_Sixth_Axis.Value == 2
                A_Jakobiyen06 = [A_Transformasyonmatris06(1:3,3);0;0;0];
            elseif app.A_Sixth_Axis.Value == 3
                A_Jakobiyen06 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Seventh_Axis
            if app.A_Seventh_Axis.Value == 1
                A_Jakobiyen07 = [A_z07(2,1)*A_p08_07(3,1) - A_z07(3,1)*A_p08_07(2,1);
                                 A_z07(3,1)*A_p08_07(1,1) - A_z07(1,1)*A_p08_07(3,1);
                                 A_z07(1,1)*A_p08_07(2,1) - A_z07(2,1)*A_p08_07(1,1);A_Rotasyon7(1:3,3)];
            elseif app.A_Seventh_Axis.Value == 2
                A_Jakobiyen07 = [A_Transformasyonmatris07(1:3,3);0;0;0];
            elseif app.A_Seventh_Axis.Value == 3
                A_Jakobiyen07 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Eighth_Axis
            if app.A_Eighth_Axis.Value == 1
                A_Jakobiyen08 = [A_z08(2,1)*A_p08_08(3,1) - A_z08(3,1)*A_p08_08(2,1);
                                 A_z08(3,1)*A_p08_08(1,1) - A_z08(1,1)*A_p08_08(3,1);
                                 A_z08(1,1)*A_p08_08(2,1) - A_z08(2,1)*A_p08_08(1,1);A_Rotasyon8(1:3,3)];
            elseif app.A_Eighth_Axis.Value == 2
                A_Jakobiyen08 = [A_Transformasyonmatris08(1:3,3);0;0;0];
            elseif app.A_Eighth_Axis.Value == 3
                A_Jakobiyen08 = [0;0;0;0;0;0];
            end                    
            
            
            A_Jakobiyen = [A_Jakobiyen01 A_Jakobiyen02 A_Jakobiyen03 A_Jakobiyen04 A_Jakobiyen05 A_Jakobiyen06 A_Jakobiyen07 A_Jakobiyen08];
             
             app.A_UITable_Jakobiyen.Data = A_Jakobiyen;
            

%             [file, path] = uiputfile('Output.txt');
% 
% if isequal(file, 0) || isequal(path, 0)
%     % Kullanıcı dosya seçim penceresini iptal etti
%     disp('Dosya seçimi iptal edildi.');
% else
%     % Verileri dosyaya yaz
%     fid = fopen(fullfile(path, file), 'w');
%     
% %     % Örnek veri (bu kısmı kendi verilerinizle güncelleyin)
%     Transformasyonmatris01 = magic(4);
%     Transformasyonmatris02 = magic(4);
%     Transformasyonmatris03 = magic(4);
%     Transformasyonmatris04 = magic(4);
%     Transformasyonmatris05 = magic(4);
%     Transformasyonmatris06 = magic(4);
%     Transformasyonmatris07 = magic(4);
%     Transformasyonmatris08 = magic(4);
% %     % Diğer matrisleri de aynı şekilde güncelleyin
% 
%     % Kısaltma için kullanılacak format
%     formatSpec = '%.4f ';
% 
%     % Her bir matrisi dosyaya yaz
%     fprintf(fid, formatSpec, Transformasyonmatris01);
%     fprintf(fid, '\n');
%     fprintf(fid, formatSpec, Transformasyonmatris02);
%     fprintf(fid, '\n');
%     fprintf(fid, formatSpec, Transformasyonmatris03);
%     fprintf(fid, '\n');
%     fprintf(fid, formatSpec, Transformasyonmatris04);
%     fprintf(fid, '\n');
%     fprintf(fid, formatSpec, Transformasyonmatris05);
%     fprintf(fid, '\n');
%     fprintf(fid, formatSpec, Transformasyonmatris06);
%     fprintf(fid, '\n');
%     fprintf(fid, formatSpec, Transformasyonmatris07);
%     fprintf(fid, '\n');
%     fprintf(fid, formatSpec, Transformasyonmatris08);
%     fprintf(fid, '\n');
%     % Diğer matrisleri de aynı şekilde dosyaya yaz
% 
%     % Dosyayı kapat
%     fclose(fid);
% 
%     disp('Veriler başarıyla dosyaya yazıldı.');
% end
            %FirstAxis
            if app.A_RevoluteButton_1.Value == 1
                FirstAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_1.Value == 1
                FirstAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_1.Value == 1
                FirstAxis = 'This Joint Has No Joint.';
            end
%           Secondaxis
            if app.A_RevoluteButton_2.Value == 1
                SecondAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_2.Value == 1
                SecondAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_2.Value == 1
                SecondAxis = 'This Joint Has No Joint.';
            end
%           ThirdAxis
            if app.A_RevoluteButton_3.Value == 1
                ThirdAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_3.Value == 1
                ThirdAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_3.Value == 1
                ThirdAxis = 'This Joint Has No Joint.';
            end
            % FourthAxis
            if app.A_RevoluteButton_4.Value == 1
                FourthAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_4.Value == 1
                FourthAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_4.Value == 1
                FourthAxis = 'This Joint Has No Joint.';
            end
%           FifthAxis
            if app.A_RevoluteButton_5.Value == 1
                FifthAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_5.Value == 1
                FifthAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_5.Value == 1
                FifthAxis = 'This Joint Has No Joint.';
            end
            %SixthAxis
            if app.A_RevoluteButton_6.Value == 1
                SixthAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_6.Value == 1
                SixthAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_6.Value == 1
                SixthAxis = 'This Joint Has No Joint.';
            end
            %Seventhaxis
            if app.A_RevoluteButton_7.Value == 1
                SeventhAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_7.Value == 1
                SeventhAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_7.Value == 1
                SeventhAxis = 'This Joint Has No Joint.';
            end
            %EighthAxis
            if app.A_RevoluteButton_8.Value == 1
                EighthAxis = 'This Joint is Revoloid';
            elseif app.A_PrismatikButton_8.Value == 1
                EighthAxis = 'This Joint is Prismatic.';
            elseif app.A_NoneButton_8.Value == 1
                EighthAxis = 'This Joint Has No Joint.';
            end
            
             %Eğer bilgisayarınızda ilk defa çalıştıracaksanız. Önce
             %alttaki 2 satırı açarak bir yere kaydedin ve matlab'den o
             %dosya kısmını açın daha sonra kaparak çok hızlı şekilde DH
             %kontrolü yapabilirsiniz.
%              [file,path] = uiputfile('Deneme.txt','w');
%              save(fullfile(path, file))

            window= fopen('Output.txt','w');
            fprintf(window, 'TRANSFORMATION MATRICES:\n');
            fprintf(window, '\n')
            fprintf(window, 'TRANSFORMATION MATRIX01:\n');
            fprintf(window, '%s \n',FirstAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{1}');
            fprintf(window,'\nTRANSFORMATION MATRIX12:\n');
            fprintf(window, '%s \n',SecondAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{2}');
            fprintf(window, 'TRANSFORMATION MATRIX23:\n');
            fprintf(window, '%s \n',ThirdAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{3}');
            fprintf(window,'\nTRANSFORMATION MATRIX34:\n');
            fprintf(window, '%s \n',FourthAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{4}');
            fprintf(window, 'TRANSFORMATION MATRIX45:\n');
            fprintf(window, '%s \n',FifthAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{5}');
            fprintf(window,'\nTRANSFORMATION MATRIX56:\n');
            fprintf(window, '%s \n',SixthAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{6}');
            fprintf(window, 'TRANSFORMATION MATRIX67:\n');
            fprintf(window, '%s \n',SeventhAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{7}');
            fprintf(window,'\nTRANSFORMATION MATRIX78:\n');
            fprintf(window, '%s \n',EighthAxis);
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris{8}');
            fprintf(window, '\n')
            fprintf(window, 'FORWARD KİNEMATİCS','\n')
            fprintf(window,'\nTRANSFORMATION MATRIX02:\n');
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris02');
            fprintf(window, 'TRANSFORMATION MATRIX03:\n');
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris03');
            fprintf(window,'\nTRANSFORMATION MATRIX04:\n');
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris04');
            fprintf(window, 'TRANSFORMATION MATRIX05:\n');
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris05');
            fprintf(window,'\nTRANSFORMATION MATRIX06:\n');
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris06');
            fprintf(window, 'TRANSFORMATION MATRIX07:\n');
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris07');
            fprintf(window,'\nTRANSFORMATION MATRIX08:\n');
            fprintf(window,'%f\t%f\t%f\t%f\n', A_Transformasyonmatris08');
            fprintf(window, '\n')
            fprintf(window,'\nJAKOBIAN:\n');
            fprintf(window,'%f\t%f\t%f\t%f\t%f\t%f\t%f\t%f\n', A_Jakobiyen');
            fclose(window);
            winopen('Output.txt');
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
%             deneme = 'deneme';
%             [file,path] = uiputfile('Output.txt','w');
%             save(fullfile(path, file), 'A_Transformasyonmatris01','A_Transformasyonmatris02','A_Transformasyonmatris03', ...
%                 'A_Transformasyonmatris04','A_Transformasyonmatris05','A_Transformasyonmatris06', ...
%                 'A_Transformasyonmatris07','A_Transformasyonmatris08','A_Jakobiyen','deneme','-ascii',"-tabs");
            

% save(fullfile(path, file), 'A_Transformasyonmatris01','%/n', 'A_Transformasyonmatris02','-ascii',"-tabs");
% save(fullfile(path, file), 'A_Transformasyonmatris02','-ascii',"-tabs");


        end

        % Button pushed function: A_DRAWButton
        function A_DRAWButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%           Atamalar
            A_Slider1NewDeger = app.A_Theta1_Slider1.Value;
            A_Slider2NewDeger = app.A_Theta2_Slider2.Value;
            A_Slider3NewDeger = app.A_Theta3_Slider3.Value;
            A_Slider4NewDeger = app.A_Theta4_Slider4.Value;
            A_Slider5NewDeger = app.A_Theta5_Slider5.Value;
            A_Slider6NewDeger = app.A_Theta6_Slider6.Value;
            A_Slider7NewDeger = app.A_Theta7_Slider7.Value;
            A_Slider8NewDeger = app.A_Theta8_Slider8.Value;
            
            
            if (A_Slider1NewDeger ~= app.A_Theta1_Slider1) || (A_Slider2NewDeger ~= app.A_Theta2_Slider2) || (A_Slider3NewDeger ~= app.A_Theta3_Slider3) || (A_Slider4NewDeger ~= app.A_Theta4_Slider4) || (A_Slider5NewDeger ~= app.A_Theta5_Slider5) || (A_Slider6NewDeger ~= app.A_Theta6_Slider6) || (A_Slider7NewDeger ~= app.A_Theta7_Slider7) || (A_Slider8NewDeger ~= app.A_Theta8_Slider8)
                cla(app.A_UIAxes1)
            end

            A_alpha0 = app.A_TextArea_alpha0.Value*pi/180;
            A_alpha1 = app.A_TextArea_alpha1.Value*pi/180;
            A_alpha2 = app.A_TextArea_alpha2.Value*pi/180;
            A_alpha3 = app.A_TextArea_alpha3.Value*pi/180;
            A_alpha4 = app.A_TextArea_alpha4.Value*pi/180;
            A_alpha5 = app.A_TextArea_alpha5.Value*pi/180;
            A_alpha6 = app.A_TextArea_alpha6.Value*pi/180;
            A_alpha7 = app.A_TextArea_alpha7.Value*pi/180;

            A_a0 = app.A_TextArea_a0.Value;
            A_a1 = app.A_TextArea_a1.Value;
            A_a2 = app.A_TextArea_a2.Value;
            A_a3 = app.A_TextArea_a3.Value;
            A_a4 = app.A_TextArea_a4.Value;
            A_a5 = app.A_TextArea_a5.Value;
            A_a6 = app.A_TextArea_a6.Value;
            A_a7 = app.A_TextArea_a7.Value;

            A_d1 = app.A_TextArea_d1.Value;
            A_d2 = app.A_TextArea_d2.Value;
            A_d3 = app.A_TextArea_d3.Value;
            A_d4 = app.A_TextArea_d4.Value;
            A_d5 = app.A_TextArea_d5.Value;
            A_d6 = app.A_TextArea_d6.Value;
            A_d7 = app.A_TextArea_d7.Value;
            A_d8 = app.A_TextArea_d8.Value;

            A_theta1 = app.A_TextArea_theta1.Value*pi/180;
            A_theta2 = app.A_TextArea_theta2.Value*pi/180;
            A_theta3 = app.A_TextArea_theta3.Value*pi/180;
            A_theta4 = app.A_TextArea_theta4.Value*pi/180;
            A_theta5 = app.A_TextArea_theta5.Value*pi/180;
            A_theta6 = app.A_TextArea_theta6.Value*pi/180;
            A_theta7 = app.A_TextArea_theta7.Value*pi/180;
            A_theta8 = app.A_TextArea_theta8.Value*pi/180;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %DH tablosu     alpha        a        d         theta
            A_dhtablosu = [     A_alpha0       A_a0	      A_d1        A_theta1;
                A_alpha1       A_a1	      A_d2        A_theta2;
                A_alpha2       A_a2	      A_d3        A_theta3;
                A_alpha3       A_a3	      A_d4        A_theta4;
                A_alpha4       A_a4	      A_d5        A_theta5;
                A_alpha5       A_a5	      A_d6        A_theta6;
                A_alpha6       A_a6	      A_d7        A_theta7;
                A_alpha7       A_a7	      A_d8        A_theta8   ];


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Transformasyon matrislerinin hesaplanması
            
            for i=1:8
                A_Transformasyonmatris{i}=[        cos(A_dhtablosu(i,4))                         -sin(A_dhtablosu(i,4))                           0                               A_dhtablosu(i,2);
                                           sin(A_dhtablosu(i,4))*cos(A_dhtablosu(i,1))      cos(A_dhtablosu(i,4))*cos(A_dhtablosu(i,1))      -sin(A_dhtablosu(i,1))         -sin(A_dhtablosu(i,1))*A_dhtablosu(i,3);
                                           sin(A_dhtablosu(i,4))*sin(A_dhtablosu(i,1))      cos(A_dhtablosu(i,4))*sin(A_dhtablosu(i,1))       cos(A_dhtablosu(i,1))          cos(A_dhtablosu(i,1))*A_dhtablosu(i,3);
                                                          0                                            0                                    0                                      1];
            end
            

            A_Transformasyonmatris{1};
            A_Transformasyonmatris{2};
            A_Transformasyonmatris{3};
            A_Transformasyonmatris{4};
            A_Transformasyonmatris{5};
            A_Transformasyonmatris{6};
            A_Transformasyonmatris{7};
            A_Transformasyonmatris{8};
            %Transformasyon matrislerinin hesaplanması
            A_Transformasyonmatris01=A_Transformasyonmatris{1};
            A_Transformasyonmatris02=A_Transformasyonmatris{1}*A_Transformasyonmatris{2};
            A_Transformasyonmatris03=A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3};
            A_Transformasyonmatris04=A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4};
            A_Transformasyonmatris05=A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5};
            A_Transformasyonmatris06=A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5}*A_Transformasyonmatris{6};
            A_Transformasyonmatris07=A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5}*A_Transformasyonmatris{6}*A_Transformasyonmatris{7};

            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %İleri kinematik transformasyon matrisi
            A_Transformasyonmatris08=A_Transformasyonmatris{1}*A_Transformasyonmatris{2}*A_Transformasyonmatris{3}*A_Transformasyonmatris{4}*A_Transformasyonmatris{5}*A_Transformasyonmatris{6}*A_Transformasyonmatris{7}*A_Transformasyonmatris{8};
            
     
            axis([-10,10,-10,10,0,10])
            xlabel(app.A_UIAxes1,'X EKSENİ');
            ylabel(app.A_UIAxes1,'Y EKSENİ');
            zlabel(app.A_UIAxes1,'Z EKSENİ');
            title(app.A_UIAxes1,'ROBOT 3D');
            
            
            x=[0 A_Transformasyonmatris01(1,4)];
            y=[0 A_Transformasyonmatris01(2,4)];
            z=[0 A_Transformasyonmatris01(3,4)];
            
            x01_02=[A_Transformasyonmatris01(1,4) A_Transformasyonmatris02(1,4)];
            y01_02=[A_Transformasyonmatris01(2,4) A_Transformasyonmatris02(2,4)];
            z01_02=[A_Transformasyonmatris01(3,4) A_Transformasyonmatris02(3,4)];
            
            x02_03=[A_Transformasyonmatris02(1,4) A_Transformasyonmatris03(1,4)];
            y02_03=[A_Transformasyonmatris02(2,4) A_Transformasyonmatris03(2,4)];
            z02_03=[A_Transformasyonmatris02(3,4) A_Transformasyonmatris03(3,4)];
            
            x03_04=[A_Transformasyonmatris03(1,4) A_Transformasyonmatris04(1,4)];
            y03_04=[A_Transformasyonmatris03(2,4) A_Transformasyonmatris04(2,4)];
            z03_04=[A_Transformasyonmatris03(3,4) A_Transformasyonmatris04(3,4)];
            
            x04_05=[A_Transformasyonmatris04(1,4) A_Transformasyonmatris05(1,4)];
            y04_05=[A_Transformasyonmatris04(2,4) A_Transformasyonmatris05(2,4)];
            z04_05=[A_Transformasyonmatris04(3,4) A_Transformasyonmatris05(3,4)];
            
            x05_06=[A_Transformasyonmatris05(1,4) A_Transformasyonmatris06(1,4)];
            y05_06=[A_Transformasyonmatris05(2,4) A_Transformasyonmatris06(2,4)];
            z05_06=[A_Transformasyonmatris05(3,4) A_Transformasyonmatris06(3,4)];
            
            x06_07=[A_Transformasyonmatris06(1,4) A_Transformasyonmatris07(1,4)];
            y06_07=[A_Transformasyonmatris06(2,4) A_Transformasyonmatris07(2,4)];
            z06_07=[A_Transformasyonmatris06(3,4) A_Transformasyonmatris07(3,4)];
            
            x07_08=[A_Transformasyonmatris07(1,4) A_Transformasyonmatris08(1,4)];
            y07_08=[A_Transformasyonmatris07(2,4) A_Transformasyonmatris08(2,4)];
            z07_08=[A_Transformasyonmatris07(3,4) A_Transformasyonmatris08(3,4)];
            
%           X=[x01_02,x02_03,x03_04,x04_05,x05_06,x06_07,x07_08];
%           Y=[y01_02,y02_03,y03_04,y04_05,y05_06,y06_07,y07_08];
%           Z=[z01_02,z02_03,z03_04,z04_05,z05_06,z06_07,z07_08];
%           
%           plot3(X,Y,Z,'Color','r','LineWidth',2);
            
            line(app.A_UIAxes1,x,y,z,'LineWidth',5,'Color','b');
            view(app.A_UIAxes1,[3 3 10])          
%           view(app.A_UIAxes1,[1 1 1])

            line(app.A_UIAxes1,x,y,z,'LineWidth',5,'Color','c');
            line(app.A_UIAxes1,x01_02,y01_02,z01_02,'LineWidth',5,'Color','b');
            line(app.A_UIAxes1,x02_03,y02_03,z02_03,'LineWidth',5,'Color','r');
            line(app.A_UIAxes1,x03_04,y03_04,z03_04,'LineWidth',5,'Color','k');
            line(app.A_UIAxes1,x04_05,y04_05,z04_05,'LineWidth',5,'Color','r');
            line(app.A_UIAxes1,x05_06,y05_06,z05_06,'LineWidth',5,'Color','g');
            line(app.A_UIAxes1,x06_07,y06_07,z06_07,'LineWidth',5,'Color','b');
            line(app.A_UIAxes1,x07_08,y07_08,z07_08,'LineWidth',5,'Color','r');
            
            app.A_UITable_Oryantasyon.Data = A_Transformasyonmatris08(1:3,1:3);
            
myData = {'Px' A_Transformasyonmatris08(1,4) ; ...
          'Py' A_Transformasyonmatris08(2,4) ;  ...
          'Pz' A_Transformasyonmatris08(3,4) ;};
            app.A_UITable_Konum.Data = myData;

        end

        % Value changing function: A_Theta1_Slider1
        function A_Theta1_Slider1ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta1.Value = changingValue;
        end

        % Value changing function: A_Theta2_Slider2
        function A_Theta2_Slider2ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta2.Value = changingValue;
        end

        % Value changing function: A_Theta3_Slider3
        function A_Theta3_Slider3ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta3.Value = changingValue;
        end

        % Value changing function: A_Theta4_Slider4
        function A_Theta4_Slider4ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta4.Value = changingValue;
        end

        % Value changing function: A_Theta5_Slider5
        function A_Theta5_Slider5ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta5.Value = changingValue;
        end

        % Value changing function: A_Theta6_Slider6
        function A_Theta6_Slider6ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta6.Value = changingValue;
        end

        % Value changing function: A_Theta7_Slider7
        function A_Theta7_Slider7ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta7.Value = changingValue;
        end

        % Value changing function: A_Theta8_Slider8
        function A_Theta8_Slider8ValueChanging(app, event)
            changingValue = event.Value;
            app.A_TextArea_theta8.Value = changingValue;
        end

        % Selection changed function: A_EklemTipiButton1
        function A_EklemTipiButton1SelectionChanged(app, event)
%            selectedButton = app.A_EklemTipiButton1.SelectedObject;

            if app.A_RevoluteButton_1.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_First_Axis.Value =1;
            elseif app.A_PrismatikButton_1.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_First_Axis.Value =2;
            elseif app.A_NoneButton_1.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_First_Axis.Value =3;
                
            end
            

        end

        % Selection changed function: A_EklemTipiButton2
        function A_EklemTipiButton2SelectionChanged(app, event)
%             selectedButton = app.A_EklemTipiButton2.SelectedObject;
            if app.A_RevoluteButton_2.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_Second_Axis.Value =1;
            elseif app.A_PrismatikButton_2.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_Second_Axis.Value =2;
            elseif app.A_NoneButton_2.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_Second_Axis.Value =3;
                
            end
            
        end

        % Selection changed function: A_EklemTipiButton3
        function A_EklemTipiButton3SelectionChanged(app, event)
%             selectedButton = app.A_EklemTipiButton3.SelectedObject;
            if app.A_RevoluteButton_3.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_Third_Axis.Value =1;
            elseif app.A_PrismatikButton_3.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_Third_Axis.Value =2;
            elseif app.A_NoneButton_3.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_Third_Axis.Value =3;
                
            end
        end

        % Selection changed function: A_EklemTipiButton4
        function A_EklemTipiButton4SelectionChanged(app, event)
%             selectedButton = app.A_EklemTipiButton4.SelectedObject;
            if app.A_RevoluteButton_4.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_Fourth_Axis.Value =1;
            elseif app.A_PrismatikButton_4.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_Fourth_Axis.Value =2;
            elseif app.A_NoneButton_4.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_Fourth_Axis.Value =3;
                
            end
        end

        % Selection changed function: A_EklemTipiButton5
        function A_EklemTipiButton5SelectionChanged(app, event)
%             selectedButton = app.A_EklemTipiButton5.SelectedObject;
            if app.A_RevoluteButton_5.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_Fifth_Axis.Value =1;
            elseif app.A_PrismatikButton_5.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_Fifth_Axis.Value =2;
            elseif app.A_NoneButton_5.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_Fifth_Axis.Value =3;
                
            end
        end

        % Selection changed function: A_EklemTipiButton6
        function A_EklemTipiButton6SelectionChanged(app, event)
%             selectedButton = app.A_EklemTipiButton6.SelectedObject;
            if app.A_RevoluteButton_6.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_Sixth_Axis.Value =1;
            elseif app.A_PrismatikButton_6.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_Sixth_Axis.Value =2;
            elseif app.A_NoneButton_6.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_Sixth_Axis.Value =3;
                
            end
        end

        % Selection changed function: A_EklemTipiButton7
        function A_EklemTipiButton7SelectionChanged(app, event)
%             selectedButton = app.A_EklemTipiButton7.SelectedObject;
            if app.A_RevoluteButton_7.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_Seventh_Axis.Value =1;
            elseif app.A_PrismatikButton_7.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_Seventh_Axis.Value =2;
            elseif app.A_NoneButton_7.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_Seventh_Axis.Value =3;
                
            end
        end

        % Selection changed function: A_EklemTipiButton8
        function A_EklemTipiButton8SelectionChanged(app, event)
%             selectedButton = app.A_EklemTipiButton8.SelectedObject;
            if app.A_RevoluteButton_8.Value == 1
%                 Jakobiyen01 = [z01(2,1)*p08_01(3,1) - z01(3,1)*p08_01(2,1);
%                                z01(3,1)*p08_01(1,1) - z01(1,1)*p08_01(3,1);
%                                z01(1,1)*p08_01(2,1) - z01(2,1)*p08_01(1,1);Rotasyon1(1:3,3)];
                app.A_Eighth_Axis.Value =1;
            elseif app.A_PrismatikButton_8.Value == 1
%                 Jakobiyen01 = [Transformasyonmatris01(1:3,3);0;0;0];
                   app.A_Eighth_Axis.Value =2;
            elseif app.A_NoneButton_8.Value == 1
%                 Jakobiyen01 = [0;0;0;0;0;0];
                app.A_Eighth_Axis.Value =3;
                
            end
        end

        % Value changed function: A_TextArea_theta1
        function A_TextArea_theta1ValueChanged(app, event)
            app.A_Theta1_Slider1.Value = app.A_TextArea_theta1.Value;
        end

        % Value changed function: A_TextArea_theta2
        function A_TextArea_theta2ValueChanged(app, event)
%             value = app.A_TextArea_theta2.Value;
            app.A_Theta2_Slider2.Value = app.A_TextArea_theta2.Value;
        end

        % Value changed function: A_TextArea_theta3
        function A_TextArea_theta3ValueChanged(app, event)
%             value = app.A_TextArea_theta3.Value;
            app.A_Theta3_Slider3.Value = app.A_TextArea_theta3.Value;
        end

        % Value changed function: A_TextArea_theta4
        function A_TextArea_theta4ValueChanged(app, event)
            app.A_Theta4_Slider4.Value = app.A_TextArea_theta4.Value;
        end

        % Value changed function: A_TextArea_theta5
        function A_TextArea_theta5ValueChanged(app, event)
            app.A_Theta5_Slider5.Value = app.A_TextArea_theta5.Value;
        end

        % Value changed function: A_TextArea_theta6
        function A_TextArea_theta6ValueChanged(app, event)
           app.A_Theta6_Slider6.Value = app.A_TextArea_theta6.Value;
        end

        % Value changed function: A_TextArea_theta7
        function A_TextArea_theta7ValueChanged(app, event)
            app.A_Theta7_Slider7.Value = app.A_TextArea_theta7.Value;
        end

        % Value changed function: A_TextArea_theta8
        function A_TextArea_theta8ValueChanged(app, event)
             app.A_Theta8_Slider8.Value = app.A_TextArea_theta8.Value;
        end

        % Value changed function: A_First_Axis
        function A_First_AxisValueChanged(app, event)
            if app.A_First_Axis.Value == 1
            app.A_RevoluteButton_1.Value = true;
            elseif app.A_First_Axis.Value == 2
                app.A_PrismatikButton_1.Value = true ;
            elseif app.A_First_Axis.Value == 3
                app.A_NoneButton_1.Value = true ;
            end
        end

        % Value changed function: A_Second_Axis
        function A_Second_AxisValueChanged(app, event)
            if app.A_Second_Axis.Value == 1
                app.A_RevoluteButton_2.Value = true;
            elseif app.A_Second_Axis.Value == 2
                app.A_PrismatikButton_2.Value = true ;
            elseif app.A_Second_Axis.Value == 3
                app.A_NoneButton_2.Value = true ;
            end
        end

        % Value changed function: A_Third_Axis
        function A_Third_AxisValueChanged(app, event)
            if app.A_Third_Axis.Value == 1
                app.A_RevoluteButton_3.Value = true;
            elseif app.A_Third_Axis.Value == 2
                app.A_PrismatikButton_3.Value = true ;
            elseif app.A_Third_Axis.Value == 3
                app.A_NoneButton_3.Value = true ;
            end
        end

        % Value changed function: A_Fourth_Axis
        function A_Fourth_AxisValueChanged(app, event)
            if app.A_Fourth_Axis.Value == 1
                app.A_RevoluteButton_4.Value = true;
            elseif app.A_Fourth_Axis.Value == 2
                app.A_PrismatikButton_4.Value = true ;
            elseif app.A_Fourth_Axis.Value == 3
                app.A_NoneButton_4.Value = true ;
            end
        end

        % Value changed function: A_Fifth_Axis
        function A_Fifth_AxisValueChanged(app, event)
            if app.A_Fifth_Axis.Value == 1
                app.A_RevoluteButton_5.Value = true;
            elseif app.A_Fifth_Axis.Value == 2
                app.A_PrismatikButton_5.Value = true ;
            elseif app.A_Fifth_Axis.Value == 3
                app.A_NoneButton_5.Value = true ;
            end
        end

        % Value changed function: A_Sixth_Axis
        function A_Sixth_AxisValueChanged(app, event)
            if app.A_Sixth_Axis.Value == 1
                app.A_RevoluteButton_6.Value = true;
            elseif app.A_Sixth_Axis.Value == 2
                app.A_PrismatikButton_6.Value = true ;
            elseif app.A_Sixth_Axis.Value == 3
                app.A_NoneButton_6.Value = true ;
            end
        end

        % Value changed function: A_Seventh_Axis
        function A_Seventh_AxisValueChanged(app, event)
            if app.A_Seventh_Axis.Value == 1
                app.A_RevoluteButton_7.Value = true;
            elseif app.A_Seventh_Axis.Value == 2
                app.A_PrismatikButton_7.Value = true ;
            elseif app.A_Seventh_Axis.Value == 3
                app.A_NoneButton_7.Value = true ;
            end
        end

        % Value changed function: A_Eighth_Axis
        function A_Eighth_AxisValueChanged(app, event)
            if app.A_Eighth_Axis.Value == 1
                app.A_RevoluteButton_8.Value = true;
            elseif app.A_Eighth_Axis.Value == 2
                app.A_PrismatikButton_8.Value = true ;
            elseif app.A_Eighth_Axis.Value == 3
                app.A_NoneButton_8.Value = true ;
            end
        end

        % Button pushed function: A_Delete_Button
        function A_Delete_ButtonPushed(app, event)
            cla(app.A_UIAxes1);
            
        end

        % Button pushed function: B_UploadButton
        function B_UploadButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%4
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%           Axis Sildirme
            B_Slider1NewDeger = app.B_Theta1_Slider1.Value;
            B_Slider2NewDeger = app.B_Theta2_Slider2.Value;
            B_Slider3NewDeger = app.B_Theta3_Slider3.Value;
            B_Slider4NewDeger = app.B_Theta4_Slider4.Value;
            B_Slider5NewDeger = app.B_Theta5_Slider5.Value;
            B_Slider6NewDeger = app.B_Theta6_Slider6.Value;
                      
            if (B_Slider1NewDeger ~= app.B_Theta1_Slider1) || (B_Slider2NewDeger ~= app.B_Theta2_Slider2) || (B_Slider3NewDeger ~= app.B_Theta3_Slider3) || (B_Slider4NewDeger ~= app.B_Theta4_Slider4) || (B_Slider5NewDeger ~= app.B_Theta5_Slider5) || (B_Slider6NewDeger ~= app.B_Theta6_Slider6)
                cla(app.A_UIAxes1)
            end
           
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%           Değiken Atama
            B_theta1 = app.B_TextArea_theta1.Value*pi/180;
            B_theta2 = app.B_TextArea_theta2.Value*pi/180;
            B_theta3 = app.B_TextArea_theta3.Value*pi/180;
            B_theta4 = app.B_TextArea_theta4.Value*pi/180;
            B_theta5 = app.B_TextArea_theta5.Value*pi/180;
            B_theta6 = app.B_TextArea_theta6.Value*pi/180;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%          dhtablosu   Alpha   a      d       theta
           B_dhtablosu = [0      0     450      B_theta1;
                       90     150    0        B_theta2+90;  
                       0      610    0        B_theta3;
                       90     200    630      B_theta4;
                       90     0      0        B_theta5;
                      -90     0      80       B_theta6];
           
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
           %Transformasyon matrislerinin hesaplanması
            
            for i=1:6
                B_Transformasyonmatris{i}=[        cos(B_dhtablosu(i,4))                         -sin(B_dhtablosu(i,4))                           0                               B_dhtablosu(i,2);
                                           sin(B_dhtablosu(i,4))*cos(B_dhtablosu(i,1))      cos(B_dhtablosu(i,4))*cos(B_dhtablosu(i,1))      -sin(B_dhtablosu(i,1))         -sin(B_dhtablosu(i,1))*B_dhtablosu(i,3);
                                           sin(B_dhtablosu(i,4))*sin(B_dhtablosu(i,1))      cos(B_dhtablosu(i,4))*sin(B_dhtablosu(i,1))       cos(B_dhtablosu(i,1))          cos(B_dhtablosu(i,1))*B_dhtablosu(i,3);
                                                          0                                            0                                    0                                      1];
            end
            

            B_Transformasyonmatris{1};
            B_Transformasyonmatris{2};
            B_Transformasyonmatris{3};
            B_Transformasyonmatris{4};
            B_Transformasyonmatris{5};
            B_Transformasyonmatris{6};
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            %Transformasyon matrislerinin hesaplanması
            B_Transformasyonmatris01=B_Transformasyonmatris{1};
            B_Transformasyonmatris02=B_Transformasyonmatris{1}*B_Transformasyonmatris{2};
            B_Transformasyonmatris03=B_Transformasyonmatris{1}*B_Transformasyonmatris{2}*B_Transformasyonmatris{3};
            B_Transformasyonmatris04=B_Transformasyonmatris{1}*B_Transformasyonmatris{2}*B_Transformasyonmatris{3}*B_Transformasyonmatris{4};
            B_Transformasyonmatris05=B_Transformasyonmatris{1}*B_Transformasyonmatris{2}*B_Transformasyonmatris{3}*B_Transformasyonmatris{4}*B_Transformasyonmatris{5};
                       
%%%%%%%%%%%%İleri kinematik transformasyon matrisi%%%%%%%%%%%%
            B_Transformasyonmatris06=B_Transformasyonmatris{1}*B_Transformasyonmatris{2}*B_Transformasyonmatris{3}*B_Transformasyonmatris{4}*B_Transformasyonmatris{5}*B_Transformasyonmatris{6};

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
% %           Hedef konumu ve yönelimi belirt
%             targetPose = [app.EditField_x.Value; app.EditField_y.Value; app.EditField_z.Value; app.B_EditField_A_Roll.Value; app.B_EditField_B_Pitch.Value; app.B_EditField_C_Yaw.Value];
% 
% %           Başlangıç değerleri
%             theta = [app.A_TextArea_theta1.Value; app.A_TextArea_theta2.Value; app.A_TextArea_theta3.Value; app.A_TextArea_theta4.Value; app.A_TextArea_theta5.Value; app.A_TextArea_theta6.Value];
% 
% %           Gradient Descent parametreleri
%             learningRate = 0.01;
%             maxIterations = 1000;
%             tolerance = 1e-6;
%             
%             for iteration = 1:maxIterations
%                 % İleri kinematik hesaplamalar
%                 currentPose = Transformasyonmatris06;
%                 
%                 % Hata hesapla
%                 error = targetPose - currentPose;
%                 
%                 % Gradient hesapla
%                 J = calculateJacobian(theta);
%                 gradient = -J' * error;
%                 
%                 % Parametre güncelle
%                 theta = theta - learningRate * gradient;
%                 
%                 % Hata kontrolü
%                 if norm(error) < tolerance
%                     break;
%                 end
%             end
%  
% %           Sonuçları göster
%             disp('Ters Kinematik Çözümü:');
%             disp('Hedef Konum ve Yönelim:');
%             disp(targetPose);
%             disp('Ters Kinematik Çözümü İle Elde Edilen Joint Açıları:');
%             disp(theta);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            axis([-10,10,-10,10,0,10])
            xlabel(app.B_UIAxes1,'X EKSENİ');
            ylabel(app.B_UIAxes1,'Y EKSENİ');
            zlabel(app.B_UIAxes1,'Z EKSENİ');
            title(app.B_UIAxes1,'ROBOT 3D');
            
            
            x=[0 B_Transformasyonmatris01(1,4)];
            y=[0 B_Transformasyonmatris01(2,4)];
            z=[0 B_Transformasyonmatris01(3,4)];
            
            x01_02=[B_Transformasyonmatris01(1,4) B_Transformasyonmatris02(1,4)];
            y01_02=[B_Transformasyonmatris01(2,4) B_Transformasyonmatris02(2,4)];
            z01_02=[B_Transformasyonmatris01(3,4) B_Transformasyonmatris02(3,4)];
            
            x02_03=[B_Transformasyonmatris02(1,4) B_Transformasyonmatris03(1,4)];
            y02_03=[B_Transformasyonmatris02(2,4) B_Transformasyonmatris03(2,4)];
            z02_03=[B_Transformasyonmatris02(3,4) B_Transformasyonmatris03(3,4)];
            
            x03_04=[B_Transformasyonmatris03(1,4) B_Transformasyonmatris04(1,4)];
            y03_04=[B_Transformasyonmatris03(2,4) B_Transformasyonmatris04(2,4)];
            z03_04=[B_Transformasyonmatris03(3,4) B_Transformasyonmatris04(3,4)];
            
            x04_05=[B_Transformasyonmatris04(1,4) B_Transformasyonmatris05(1,4)];
            y04_05=[B_Transformasyonmatris04(2,4) B_Transformasyonmatris05(2,4)];
            z04_05=[B_Transformasyonmatris04(3,4) B_Transformasyonmatris05(3,4)];
            
            x05_06=[B_Transformasyonmatris05(1,4) B_Transformasyonmatris06(1,4)];
            y05_06=[B_Transformasyonmatris05(2,4) B_Transformasyonmatris06(2,4)];
            z05_06=[B_Transformasyonmatris05(3,4) B_Transformasyonmatris06(3,4)];
                      
%            plot3(X,Y,Z,'Color','r','LineWidth',2);
           
            plot3(app.B_UIAxes1,x,y,z,'LineWidth',5,'Color','c');
            
            line(app.B_UIAxes1,x,y,z,'LineWidth',5,'Color','c');
            line(app.B_UIAxes1,x01_02,y01_02,z01_02,'LineWidth',5,'Color','b');
            line(app.B_UIAxes1,x02_03,y02_03,z02_03,'LineWidth',5,'Color','r');
            line(app.B_UIAxes1,x03_04,y03_04,z03_04,'LineWidth',5,'Color','k');
            line(app.B_UIAxes1,x04_05,y04_05,z04_05,'LineWidth',5,'Color','r');
            line(app.B_UIAxes1,x05_06,y05_06,z05_06,'LineWidth',5,'Color','g');
            
            app.B_UITable_ForwardKinematic.Data = B_Transformasyonmatris06;
            
            app.B_UITable_Oryantasyon.Data = B_Transformasyonmatris06(1:3,1:3);
            
            myData = {'Px' B_Transformasyonmatris06(1,4) ; ...
          'Py' B_Transformasyonmatris06(2,4) ;  ...
          'Pz' B_Transformasyonmatris06(3,4) ;};
            app.B_UITable2_Location.Data = myData;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
%           Alpha a ve d değerleri sabit olduğu için istenmediği sürece değiştirilemez. 
            if app.B_EnableConrolCheckBox.Value == 1
                app.B_TextArea_alpha0.Enable = "on";
                app.B_TextArea_alpha1.Enable = "on";
                app.B_TextArea_alpha2.Enable = "on";
                app.B_TextArea_alpha3.Enable = "on";
                app.B_TextArea_alpha4.Enable = "on";
                app.B_TextArea_alpha5.Enable = "on";

                app.B_TextArea_a0.Enable ="on";
                app.B_TextArea_a1.Enable ="on";
                app.B_TextArea_a2.Enable ="on";
                app.B_TextArea_a3.Enable ="on";
                app.B_TextArea_a4.Enable ="on";
                app.B_TextArea_a5.Enable ="on";

                app.B_TextArea_d1.Enable = "on";
                app.B_TextArea_d2.Enable = "on";
                app.B_TextArea_d3.Enable = "on";
                app.B_TextArea_d4.Enable = "on";
                app.B_TextArea_d5.Enable = "on";
                app.B_TextArea_d6.Enable = "on";

            else
                app.B_TextArea_alpha0.Enable = "off";
                app.B_TextArea_alpha1.Enable = "off";
                app.B_TextArea_alpha2.Enable = "off";
                app.B_TextArea_alpha3.Enable = "off";
                app.B_TextArea_alpha4.Enable = "off";
                app.B_TextArea_alpha5.Enable = "off";

                app.B_TextArea_a0.Enable ="off";
                app.B_TextArea_a1.Enable ="off";
                app.B_TextArea_a2.Enable ="off";
                app.B_TextArea_a3.Enable ="off";
                app.B_TextArea_a4.Enable ="off";
                app.B_TextArea_a5.Enable ="off";

                app.B_TextArea_d1.Enable = "off";
                app.B_TextArea_d2.Enable = "off";
                app.B_TextArea_d3.Enable = "off";
                app.B_TextArea_d4.Enable = "off";
                app.B_TextArea_d5.Enable = "off";
                app.B_TextArea_d6.Enable = "off";

                               
            end
               
            B_th1 = app.B_TextArea_theta1.Value;
            B_th2 = app.B_TextArea_theta2.Value;
            B_th3 = app.B_TextArea_theta3.Value;
            B_th4 = app.B_TextArea_theta4.Value;
            B_th5 = app.B_TextArea_theta5.Value;
            B_th6 = app.B_TextArea_theta6.Value;

            
            opt = simset('SrcWorkSpace','current');
            sim('Simscape',[],opt)


%             %Ters kinematik Hesaplanması
%             Matris_Tamamlama = [0 0 0 1];
%             B_Rotasyon001 = B_Transformasyonmatris01(1:3,1:3);
%             B_Rotasyon001_Konum = -transpose(B_Rotasyon001)*B_Transformasyonmatris01(1:3,4);
% 
%             TranspozT01 = vertcat(horzcat(transpose(B_Rotasyon001) , B_Rotasyon001_Konum) , Matris_Tamamlama);

 
%             B_Transformasyonmatris01' * B_Transformasyonmatris06 = B_Transformasyonmatris01' * B_Transformasyonmatris01 * B_Transformasyonmatris{2} ** B_Transformasyonmatris{3} ** B_Transformasyonmatris{4} ** B_Transformasyonmatris{5} ** B_Transformasyonmatris{6} *

%             B_Ters_T06 = [r11 r12 r13 px;
%                           r21 r22 r23 py;
%                           r31 r32 r33 pz;
%                            0   0   0   1];


        end

        % Value changing function: B_Theta1_Slider1
        function B_Theta1_Slider1ValueChanging(app, event)
            changingValue = event.Value;
            app.B_TextArea_theta1.Value = changingValue;            
        end

        % Value changing function: B_Theta2_Slider2
        function B_Theta2_Slider2ValueChanging(app, event)
            changingValue = event.Value;
            app.B_TextArea_theta2.Value = changingValue; 
        end

        % Value changing function: B_Theta3_Slider3
        function B_Theta3_Slider3ValueChanging(app, event)
            changingValue = event.Value;
            app.B_TextArea_theta3.Value = changingValue; 
        end

        % Value changing function: B_Theta4_Slider4
        function B_Theta4_Slider4ValueChanging(app, event)
            changingValue = event.Value;
            app.B_TextArea_theta4.Value = changingValue; 
        end

        % Value changing function: B_Theta5_Slider5
        function B_Theta5_Slider5ValueChanging(app, event)
            changingValue = event.Value;
            app.B_TextArea_theta5.Value = changingValue; 
        end

        % Value changing function: B_Theta6_Slider6
        function B_Theta6_Slider6ValueChanging(app, event)
            changingValue = event.Value;
            app.B_TextArea_theta6.Value = changingValue; 
        end

        % Value changed function: B_TextArea_theta1
        function B_TextArea_theta1ValueChanged(app, event)
            app.B_Theta1_Slider1.Value = app.B_TextArea_theta1.Value;
        end

        % Value changed function: B_TextArea_theta2
        function B_TextArea_theta2ValueChanged(app, event)
            app.B_Theta2_Slider2.Value = app.B_TextArea_theta2.Value;
        end

        % Value changed function: B_TextArea_theta3
        function B_TextArea_theta3ValueChanged(app, event)
            app.B_Theta3_Slider3.Value = app.B_TextArea_theta3.Value;
        end

        % Value changed function: B_TextArea_theta4
        function B_TextArea_theta4ValueChanged(app, event)
            app.B_Theta4_Slider4.Value = app.B_TextArea_theta4.Value;
        end

        % Value changed function: B_TextArea_theta5
        function B_TextArea_theta5ValueChanged(app, event)
            app.B_Theta5_Slider5.Value = app.B_TextArea_theta5.Value;
        end

        % Value changed function: B_TextArea_theta6
        function B_TextArea_theta6ValueChanged(app, event)
            app.B_Theta6_Slider6.Value = app.B_TextArea_theta6.Value;
        end

        % Value changed function: B_EnableConrolCheckBox
        function B_EnableConrolCheckBoxValueChanged(app, event)
            %         
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
%           Alpha a ve d değerleri sabit olduğu için istenmediği sürece değiştirilemez. 
                if app.B_EnableConrolCheckBox.Value == 1
                    app.B_TextArea_alpha0.Enable = "on";
                    app.B_TextArea_alpha1.Enable = "on";
                    app.B_TextArea_alpha2.Enable = "on";
                    app.B_TextArea_alpha3.Enable = "on";
                    app.B_TextArea_alpha4.Enable = "on";
                    app.B_TextArea_alpha5.Enable = "on";
    
                    app.B_TextArea_a0.Enable ="on";
                    app.B_TextArea_a1.Enable ="on";
                    app.B_TextArea_a2.Enable ="on";
                    app.B_TextArea_a3.Enable ="on";
                    app.B_TextArea_a4.Enable ="on";
                    app.B_TextArea_a5.Enable ="on";
    
                    app.B_TextArea_d1.Enable = "on";
                    app.B_TextArea_d2.Enable = "on";
                    app.B_TextArea_d3.Enable = "on";
                    app.B_TextArea_d4.Enable = "on";
                    app.B_TextArea_d5.Enable = "on";
                    app.B_TextArea_d6.Enable = "on";

                else
                    app.B_TextArea_alpha0.Enable = "off";
                    app.B_TextArea_alpha1.Enable = "off";
                    app.B_TextArea_alpha2.Enable = "off";
                    app.B_TextArea_alpha3.Enable = "off";
                    app.B_TextArea_alpha4.Enable = "off";
                    app.B_TextArea_alpha5.Enable = "off";
    
                    app.B_TextArea_a0.Enable ="off";
                    app.B_TextArea_a1.Enable ="off";
                    app.B_TextArea_a2.Enable ="off";
                    app.B_TextArea_a3.Enable ="off";
                    app.B_TextArea_a4.Enable ="off";
                    app.B_TextArea_a5.Enable ="off";
    
                    app.B_TextArea_d1.Enable = "off";
                    app.B_TextArea_d2.Enable = "off";
                    app.B_TextArea_d3.Enable = "off";
                    app.B_TextArea_d4.Enable = "off";
                    app.B_TextArea_d5.Enable = "off";
                    app.B_TextArea_d6.Enable = "off";
    
                                   
                end
        end

        % Button pushed function: B_DELETEButton
        function B_DELETEButtonPushed(app, event)
            cla(app.B_UIAxes1);
        end

        % Button pushed function: C_Rotasyon_Button
        function C_Rotasyon_ButtonPushed(app, event)
            C_r = [app.A1.Value app.B1.Value app.G1.Value;
                   app.A2.Value app.B2.Value app.G2.Value;
                   app.A3.Value app.B3.Value app.G3.Value];
            if app.C_SabitRPY_XYZ.Value == 1 || app.C_Hareketli_ZYX.Value == 1          
%%%%%%%%%%%%Roll Pitch Yaw XYZ 1
            RollPitchYawXYZ = [cosd(app.A.Value)*cosd(app.B.Value)    cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value)-sind(app.A.Value)*cosd(app.G.Value)   cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value);
                               sind(app.A.Value)* cosd(app.B.Value)   sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value)+cosd(app.A.Value)*cosd(app.G.Value)   sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value);
                              -sind(app.B.Value)                            cosd(app.B.Value)*sind(app.G.Value)                                                                                                           cosd(app.B.Value)*cosd(app.G.Value)];
            
            app.C_UITable_Rotasyon.Data = RollPitchYawXYZ;
%             C_Oryantasyon = [app.A1.Value app.B1.Value app.G1.Value;
%                              app.A2.Value app.B2.Value app.G2.Value;
%                              app.A3.Value app.B3.Value app.G3.Value];
%             beta = atan2d(-C_r(3,1),sqrt(C_r(1,1)^2+C_r(2,1)^2));
%             alfa = atan2d(C_r(2,1)/cosd(beta),C_r(1,1)/cosd(beta));
%             gama = atan2d(C_r(3,2)/cosd(beta) , C_r(3,3)/cosd(beta));
%             app.Alpha_Cikti.Value = alfa;
%             app.Beta_Cikti.Value = beta;
%             app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YXZ 2 
           elseif app.C_SabitRPY_YXZ.Value == 1 || app.C_Hareketli_ZXY.Value == 1
               RollPitchYawYXZ = [-sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)   -sind(app.A.Value)*cosd(app.B.Value)   sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value);
                                   cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)    cosd(app.A.Value)*cosd(app.B.Value)   -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value);
                                  -cosd(app.B.Value)*sind(app.G.Value)                                                                             sind(app.B.Value)                           cosd(app.B.Value)*cosd(app.G.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawYXZ;
%            beta = atan2d(C_r(3,2),sqrt(C_r(1,2)^2+C_r(2,2)^2));
%            alfa = atan2d(C_r(1,2)/-cosd(beta),C_r(2,2)/cosd(beta));
%            gama = atan2d(C_r(3,1)/-cosd(beta) , C_r(3,3)/cosd(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;

%%%%%%%%%%%Roll Pitch Yaw XZY 3
           elseif app.C_SabitRPY_XZY.Value == 1 || app.C_Hareketli_YZX.Value == 1
               RollPitchYawXZY =[cosd(app.A.Value)*cosd(app.B.Value)   -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)     cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value);
                                 sind(app.B.Value)                      cosd(app.B.Value)*cosd(app.G.Value)                                                            -cosd(app.B.Value)*sind(app.G.Value)  ;
                                -sind(app.A.Value)* cosd(app.B.Value)   sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)    -sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawXZY;
%            beta = atan2d(C_r(2,1),sqrt(C_r(2,2)^2+C_r(2,3)^2));
%            alfa = atan2d(C_r(3,1)/-cosd(beta),C_r(1,1)/cosd(beta));
%            gama = atan2d(C_r(2,3)/-cosd(beta) , C_r(2,2)/cosd(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;

 %%%%%%%%%%%Roll Pitch Yaw YZX 4%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           elseif app.C_SabitRPY_YZX.Value == 1 || app.C_Hareketli_XZY.Value == 1
               RollPitchYawYZX =[cosd(app.B.Value)*cosd(app.G.Value)                                                             -sind(app.B.Value)                      cosd(app.B.Value)*sind(app.G.Value);
                                 cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)      cosd(app.A.Value)*cosd(app.B.Value)    cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)  ;
                                 sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)      sind(app.A.Value)*cosd(app.B.Value)    sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawYZX;
%            beta = atan2d(-C_r(1,2),sqrt(C_r(1,1)^2+C_r(1,3)^2));
%            alfa = atan2d(C_r(3,2)/cosd(beta),C_r(2,2)/cosd(beta));
%            gama = atan2d(C_r(1,3)/cosd(beta) , C_r(1,1)/cosd(beta));
%             app.Alpha_Cikti.Value = alfa;
%             app.Beta_Cikti.Value = beta;
%             app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw ZXY 5
           elseif app.C_SabitRPY_ZXY.Value == 1 || app.C_Hareketli_YXZ.Value == 1
               RollPitchYawZXY =[sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)      sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)       sind(app.A.Value)*cosd(app.B.Value);
                                 cosd(app.B.Value)*sind(app.G.Value)                                                              cosd(app.B.Value)*cosd(app.G.Value)                                                              -sind(app.B.Value)                      
                                 cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)      cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)       cosd(app.A.Value)*cosd(app.B.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawZXY;
%            beta = atan2d(-C_r(2,3),sqrt(C_r(1,3)^2+C_r(3,3)^2));
%            alfa = atan2d(C_r(1,3)/cosd(beta),C_r(3,3)/cosd(beta));
%            gama = atan2d(C_r(2,1)/cosd(beta) , C_r(2,2)/cosd(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw ZXY 6
           elseif app.C_SabitRPY_ZYX.Value == 1 || app.C_Hareketli_XYZ.Value == 1
               RollPitchYawZYX =[cosd(app.B.Value)*cosd(app.G.Value)                                                             -cosd(app.B.Value)*sind(app.G.Value)                                                               sind(app.B.Value);
                                 sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)     -sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)       -sind(app.A.Value)*cosd(app.B.Value);                    
                                -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)      cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)       cosd(app.A.Value)*cosd(app.B.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawZYX;
%            beta = atan2d(C_r(1,3),sqrt(C_r(1,1)^2+C_r(1,2)^2));
%            alfa = atan2d(C_r(2,3)/-cosd(beta),C_r(3,3)/cosd(beta));
%            gama = atan2d(C_r(1,2)/-cosd(beta) , C_r(1,1)/cosd(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw XYX 7
           elseif app.C_SabitRPY_XYX.Value == 1 || app.C_Hareketli_XYX.Value == 1
               RollPitchYawXYX =[cosd(app.B.Value)                         sind(app.B.Value)*sind(app.G.Value)                                                              sind(app.B.Value)*cosd(app.G.Value);
                                 sind(app.A.Value)*sind(app.B.Value)      -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value);                    
                                -cosd(app.A.Value)*sind(app.B.Value)       cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)      cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawXYX;
%            beta = atan2d(sqrt(C_r(1,2)^2+C_r(1,3)^2), C_r(1,1));
%            alfa = atan2d(C_r(2,1)/sind(beta),C_r(3,1)/-sind(beta));
%            gama = atan2d(C_r(1,2)/sind(beta) , C_r(1,3)/sind(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw XZX 8
           elseif app.C_SabitRPY_XZX.Value == 1 || app.C_Hareketli_XZX.Value == 1
               RollPitchYawXZX =[cosd(app.B.Value)                        -sind(app.B.Value)*cosd(app.G.Value)                                                              sind(app.B.Value)*sind(app.G.Value);
                                 cosd(app.A.Value)*sind(app.B.Value)       cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)     -cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value);                    
                                sind(app.A.Value)*sind(app.B.Value)        sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawXZX;
%            beta = atan2d(sqrt(C_r(2,1)^2+C_r(3,1)^2), C_r(1,1));
%            alfa = atan2d(C_r(3,1)/sind(beta),C_r(2,1)/sind(beta));
%            gama = atan2d(C_r(1,3)/sind(beta) , C_r(1,2)/-sind(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YXY 9
           elseif app.C_SabitRPY_YXY.Value == 1 || app.C_Hareketli_YXY.Value == 1
               RollPitchYawYXY =[-sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)   sind(app.A.Value)*sind(app.B.Value)     sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value);
                                 sind(app.B.Value)*sind(app.G.Value)                                                            cosd(app.B.Value)                      -sind(app.B.Value)*cosd(app.G.Value)                                                        ;
                                -cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)    cosd(app.A.Value)*sind(app.B.Value)     cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawYXY;
%            beta = atan2d(sqrt(C_r(1,2)^2+C_r(3,2)^2), C_r(2,2));
%            alfa = atan2d(C_r(1,2)/sind(beta),C_r(3,2)/sind(beta));
%            gama = atan2d(C_r(2,1)/sind(beta) , C_r(2,3)/-sind(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YZY 10
           elseif app.C_SabitRPY_YZY.Value == 1 || app.C_Hareketli_YZY.Value == 1
               RollPitchYawYZY =[cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)   -cosd(app.A.Value)*sind(app.B.Value)     cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value);
                                 sind(app.B.Value)*cosd(app.G.Value)                                                            cosd(app.B.Value)                       sind(app.B.Value)*sind(app.G.Value)                                                        ;
                                -sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)    sind(app.A.Value)*sind(app.B.Value)    -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawYZY;
%            beta = atan2d(sqrt(C_r(2,1)^2+C_r(2,3)^2), C_r(2,2));
%            alfa = atan2d(C_r(3,2)/sind(beta),C_r(1,2)/-sind(beta));
%            gama = atan2d(C_r(2,3)/sind(beta) , C_r(2,1)/sind(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YZX 11
           elseif app.C_SabitRPY_ZXZ.Value == 1 || app.C_Hareketli_ZXZ.Value == 1
               RollPitchYawZXZ =[-sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)         sind(app.A.Value)*sind(app.B.Value);
                                 cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)      cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)         -cosd(app.A.Value)*sind(app.B.Value) ;
                                 sind(app.B.Value)*sind(app.G.Value)                                                              sind(app.B.Value)*cosd(app.G.Value)                                                                  cosd(app.B.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawZXZ;
%            beta = atan2d(sqrt(C_r(3,1)^2+C_r(3,2)^2), C_r(3,3));
%            alfa = atan2d(C_r(1,3)/sind(beta),C_r(2,3)/-sind(beta));
%            gama = atan2d(C_r(3,1)/sind(beta) , C_r(3,2)/sind(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YZX 12
           elseif app.C_SabitRPY_ZYZ.Value == 1 || app.C_Hareketli_ZYZ.Value == 1
               RollPitchYawZYZ =[cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)     -cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)               cosd(app.A.Value)*sind(app.B.Value);
                                 sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)               sind(app.A.Value)*sind(app.B.Value) ;
                                 -sind(app.B.Value)*cosd(app.G.Value)                                                              sind(app.B.Value)*sind(app.G.Value)                                                                        cosd(app.B.Value)];
           app.C_UITable_Rotasyon.Data = RollPitchYawZYZ;
%            beta = atan2d(sqrt(C_r(1,3)^2+C_r(2,3)^2), C_r(3,3));
%            alfa = atan2d(C_r(1,3)/sind(beta),C_r(2,3)/-sind(beta));
%            gama = atan2d(C_r(3,2)/sind(beta) , C_r(3,1)/-sind(beta));
%            app.Alpha_Cikti.Value = alfa;
%            app.Beta_Cikti.Value = beta;
%            app.Gama_Cikti.Value = gama;
            end

%             C_Teta = atan2d(sqrt((C_r(3,2)-C_r(2,3))^2+(C_r(1,3)-C_r(3,1))^2+(C_r(2,1)-C_r(1,2))^2),C_r(1,1)+C_r(2,2)+C_r(3,3)-1);
%             K_Vektor = 1/2*sind(C_Teta)*[C_r(2,3)-C_r(3,2);
%                                          C_r(1,3)-C_r(3,1);
%                                          C_r(2,1)-C_r(1,2)];
%             app.UITable.Data = K_Vektor;

%%%%%%%%%%%EULER AÇI SETİ XYZ ----- 1 -----
%            elseif app.C_Hareketli_XYZ == 1
%                EULERXYZ =[cosd(app.B.Value)*cods(app.G.Value)                                                              cosd(app.B.Value)*sind(app.G.Value)                                                               sind(app.B.Value);
%                                  sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)     -sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)       -sind(app.a.Value)*cosd(app.B.Value);                    
%                                 -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)      cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)       cosd(app.A.Value)*cosd(app.B.Value)];
%            app.C_UITable_Rotasyon.Data = EULERXYZ;
% %%%%%%%%%%%EULER AÇI SETİ XYZ ----- 2 -----
%            elseif app.C_Hareketli_XZY == 1
%                EULERXZY =[cosd(app.B.Value)*cosd(app.G.Value)                                                             -sind(app.B.Value)                                                     cosd(app.B.Value)*sind(app.G.Value);
%                                  sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)      cosd(app.A.Value)*cosd(app.B.Value)    cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)  ;
%                                  sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)      sind(app.A.Value)*cosd(app.B.Value)    sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = EULERXZY;
% %%%%%%%%%%%EULER AÇI SETİ XYZ ----- 3 -----
%            elseif app.C_Hareketli_YXZ == 1
%                EULERYXZ =[sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)      sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)       sind(app.A.Value)*cosd(app.B.Value);
%                                  cosd(app.B.Value)*sind(app.G.Value)                                                              cosd(app.B.Value)*cods(app.G.Value)                                                              -sind(app.B.Value)                      
%                                  cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)      cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)       cosd(app.A.Value)*cosd(app.B.Value)];
%            app.C_UITable_Rotasyon.Data = EULERYXZ; 
% %%%%%%%%%%%EULER AÇI SETİ XYZ ----- 4 -----
%            elseif app.C_Hareketli_YXZ == 1
%                RollPitchYawYZX =[cosd(app.A.Value)*cosd(app.B.Value)   -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)     cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*sind(app.G.Value);
%                                  sind(app.B.Value)                            cosd(app.B.Value)*cosd(app.G.Value)                                                                              cosd(app.B.Value)*sind(app.G.Value)  ;
%                                 -sind(app.A.Value)* cosd(app.B.Value)   sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)    -sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawYZX; 
%             end 
                                       

        end

        % Button pushed function: C_Aci_Button
        function C_Aci_ButtonPushed(app, event)
             C_r = [app.A1.Value app.B1.Value app.G1.Value;
                   app.A2.Value app.B2.Value app.G2.Value;
                   app.A3.Value app.B3.Value app.G3.Value];
            if app.C_SabitRPY_XYZ.Value == 1 || app.C_Hareketli_ZYX.Value == 1          
%%%%%%%%%%%%Roll Pitch Yaw XYZ 1
%             RollPitchYawXYZ = [cosd(app.A.Value)*cosd(app.B.Value)    cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value)-sind(app.A.Value)*cosd(app.G.Value)   cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value);
%                                sind(app.A.Value)* cosd(app.B.Value)   sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value)+cosd(app.A.Value)*cosd(app.G.Value)   sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value);
%                               -sind(app.B.Value)                            cosd(app.B.Value)*sind(app.G.Value)                                                                                                           cosd(app.B.Value)*cosd(app.G.Value)];
%             
%             app.C_UITable_Rotasyon.Data = RollPitchYawXYZ;
%             C_Oryantasyon = [app.A1.Value app.B1.Value app.G1.Value;
%                              app.A2.Value app.B2.Value app.G2.Value;
%                              app.A3.Value app.B3.Value app.G3.Value];
            beta = atan2d(-C_r(3,1),sqrt(C_r(1,1)^2+C_r(2,1)^2));
            alfa = atan2d(C_r(2,1)/cosd(beta),C_r(1,1)/cosd(beta));
            gama = atan2d(C_r(3,2)/cosd(beta) , C_r(3,3)/cosd(beta));
            app.Alpha_Cikti.Value = alfa;
            app.Beta_Cikti.Value = beta;
            app.Gama_Cikti.Value = gama;
            
%%%%%%%%%%%Roll Pitch Yaw YXZ 2 
           elseif app.C_SabitRPY_YXZ.Value == 1 || app.C_Hareketli_ZXY.Value == 1
%                RollPitchYawYXZ = [-sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)   -sind(app.A.Value)*cosd(app.B.Value)   sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value);
%                                    cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)    cosd(app.A.Value)*cosd(app.B.Value)   -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value);
%                                   -cosd(app.B.Value)*sind(app.G.Value)                                                                             sind(app.B.Value)                           cosd(app.B.Value)*cosd(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawYXZ;
           beta = atan2d(C_r(3,2),sqrt(C_r(1,2)^2+C_r(2,2)^2));
           alfa = atan2d(C_r(1,2)/-cosd(beta),C_r(2,2)/cosd(beta));
           gama = atan2d(C_r(3,1)/-cosd(beta) , C_r(3,3)/cosd(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;

%%%%%%%%%%%Roll Pitch Yaw XZY 3
           elseif app.C_SabitRPY_XZY.Value == 1 || app.C_Hareketli_YZX.Value == 1
%                RollPitchYawXZY =[cosd(app.A.Value)*cosd(app.B.Value)   -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)     cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value);
%                                  sind(app.B.Value)                      cosd(app.B.Value)*cosd(app.G.Value)                                                            -cosd(app.B.Value)*sind(app.G.Value)  ;
%                                 -sind(app.A.Value)* cosd(app.B.Value)   sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)    -sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawXZY;
           beta = atan2d(C_r(2,1),sqrt(C_r(2,2)^2+C_r(2,3)^2));
           alfa = atan2d(C_r(3,1)/-cosd(beta),C_r(1,1)/cosd(beta));
           gama = atan2d(C_r(2,3)/-cosd(beta) , C_r(2,2)/cosd(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;

 %%%%%%%%%%%Roll Pitch Yaw YZX 4%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           elseif app.C_SabitRPY_YZX.Value == 1 || app.C_Hareketli_XZY.Value == 1
%                RollPitchYawYZX =[cosd(app.B.Value)*cosd(app.G.Value)                                                             -sind(app.B.Value)                      cosd(app.B.Value)*sind(app.G.Value);
%                                  cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)      cosd(app.A.Value)*cosd(app.B.Value)    cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)  ;
%                                  sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)      sind(app.A.Value)*cosd(app.B.Value)    sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawYZX;
           beta = atan2d(-C_r(1,2),sqrt(C_r(1,1)^2+C_r(1,3)^2));
           alfa = atan2d(C_r(3,2)/cosd(beta),C_r(2,2)/cosd(beta));
           gama = atan2d(C_r(1,3)/cosd(beta) , C_r(1,1)/cosd(beta));
            app.Alpha_Cikti.Value = alfa;
            app.Beta_Cikti.Value = beta;
            app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw ZXY 5
           elseif app.C_SabitRPY_ZXY.Value == 1 || app.C_Hareketli_YXZ.Value == 1
%                RollPitchYawZXY =[sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)      sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)       sind(app.A.Value)*cosd(app.B.Value);
%                                  cosd(app.B.Value)*sind(app.G.Value)                                                              cosd(app.B.Value)*cosd(app.G.Value)                                                              -sind(app.B.Value)                      
%                                  cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)      cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)       cosd(app.A.Value)*cosd(app.B.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawZXY;
           beta = atan2d(-C_r(2,3),sqrt(C_r(1,3)^2+C_r(3,3)^2));
           alfa = atan2d(C_r(1,3)/cosd(beta),C_r(3,3)/cosd(beta));
           gama = atan2d(C_r(2,1)/cosd(beta) , C_r(2,2)/cosd(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw ZXY 6
           elseif app.C_SabitRPY_ZYX.Value == 1 || app.C_Hareketli_XYZ.Value == 1
%                RollPitchYawZYX =[cosd(app.B.Value)*cosd(app.G.Value)                                                             -cosd(app.B.Value)*sind(app.G.Value)                                                               sind(app.B.Value);
%                                  sind(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)     -sind(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)       -sind(app.A.Value)*cosd(app.B.Value);                    
%                                 -cosd(app.A.Value)*sind(app.B.Value)*cosd(app.G.Value) + sind(app.A.Value)*sind(app.G.Value)      cosd(app.A.Value)*sind(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)       cosd(app.A.Value)*cosd(app.B.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawZYX;
           beta = atan2d(C_r(1,3),sqrt(C_r(1,1)^2+C_r(1,2)^2));
           alfa = atan2d(C_r(2,3)/-cosd(beta),C_r(3,3)/cosd(beta));
           gama = atan2d(C_r(1,2)/-cosd(beta) , C_r(1,1)/cosd(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw XYX 7
           elseif app.C_SabitRPY_XYX.Value == 1 || app.C_Hareketli_XYX.Value == 1
%                RollPitchYawXYX =[cosd(app.B.Value)                         sind(app.B.Value)*sind(app.G.Value)                                                              sind(app.B.Value)*cosd(app.G.Value);
%                                  sind(app.A.Value)*sind(app.B.Value)      -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value);                    
%                                 -cosd(app.A.Value)*sind(app.B.Value)       cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)      cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawXYX;
           beta = atan2d(sqrt(C_r(1,2)^2+C_r(1,3)^2), C_r(1,1));
           alfa = atan2d(C_r(2,1)/sind(beta),C_r(3,1)/-sind(beta));
           gama = atan2d(C_r(1,2)/sind(beta) , C_r(1,3)/sind(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw XZX 8
           elseif app.C_SabitRPY_XZX.Value == 1 || app.C_Hareketli_XZX.Value == 1
%                RollPitchYawXZX =[cosd(app.B.Value)                        -sind(app.B.Value)*cosd(app.G.Value)                                                              sind(app.B.Value)*sind(app.G.Value);
%                                  cosd(app.A.Value)*sind(app.B.Value)       cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)     -cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value);                    
%                                 sind(app.A.Value)*sind(app.B.Value)        sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawXZX;
           beta = atan2d(sqrt(C_r(2,1)^2+C_r(3,1)^2), C_r(1,1));
           alfa = atan2d(C_r(3,1)/sind(beta),C_r(2,1)/sind(beta));
           gama = atan2d(C_r(1,3)/sind(beta) , C_r(1,2)/-sind(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YXY 9
           elseif app.C_SabitRPY_YXY.Value == 1 || app.C_Hareketli_YXY.Value == 1
%                RollPitchYawYXY =[-sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)   sind(app.A.Value)*sind(app.B.Value)     sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value);
%                                  sind(app.B.Value)*sind(app.G.Value)                                                            cosd(app.B.Value)                      -sind(app.B.Value)*cosd(app.G.Value)                                                        ;
%                                 -cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)    cosd(app.A.Value)*sind(app.B.Value)     cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawYXY;
           beta = atan2d(sqrt(C_r(1,2)^2+C_r(3,2)^2), C_r(2,2));
           alfa = atan2d(C_r(1,2)/sind(beta),C_r(3,2)/sind(beta));
           gama = atan2d(C_r(2,1)/sind(beta) , C_r(2,3)/-sind(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YZY 10
           elseif app.C_SabitRPY_YZY.Value == 1 || app.C_Hareketli_YZY.Value == 1
%                RollPitchYawYZY =[cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)   -cosd(app.A.Value)*sind(app.B.Value)     cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value);
%                                  sind(app.B.Value)*cosd(app.G.Value)                                                            cosd(app.B.Value)                       sind(app.B.Value)*sind(app.G.Value)                                                        ;
%                                 -sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)    sind(app.A.Value)*sind(app.B.Value)    -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawYZY;
           beta = atan2d(sqrt(C_r(2,1)^2+C_r(2,3)^2), C_r(2,2));
           alfa = atan2d(C_r(3,2)/sind(beta),C_r(1,2)/-sind(beta));
           gama = atan2d(C_r(2,3)/sind(beta) , C_r(2,1)/sind(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YZX 11
           elseif app.C_SabitRPY_ZXZ.Value == 1 || app.C_Hareketli_ZXZ.Value == 1
%                RollPitchYawZXZ =[-sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - cosd(app.A.Value)*sind(app.G.Value)         sind(app.A.Value)*sind(app.B.Value);
%                                  cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + sind(app.A.Value)*cosd(app.G.Value)      cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)         -cosd(app.A.Value)*sind(app.B.Value) ;
%                                  sind(app.B.Value)*sind(app.G.Value)                                                              sind(app.B.Value)*cosd(app.G.Value)                                                                  cosd(app.B.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawZXZ;
           beta = atan2d(sqrt(C_r(3,1)^2+C_r(3,2)^2), C_r(3,3));
           alfa = atan2d(C_r(1,3)/sind(beta),C_r(2,3)/-sind(beta));
           gama = atan2d(C_r(3,1)/sind(beta) , C_r(3,2)/sind(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
%%%%%%%%%%%Roll Pitch Yaw YZX 12
           elseif app.C_SabitRPY_ZYZ.Value == 1 || app.C_Hareketli_ZYZ.Value == 1
%                RollPitchYawZXZ =[cosd(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) - sind(app.A.Value)*sind(app.G.Value)     -cosd(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) - sind(app.A.Value)*cosd(app.G.Value)               cosd(app.A.Value)*sind(app.B.Value);
%                                  sind(app.A.Value)*cosd(app.B.Value)*cosd(app.G.Value) + cosd(app.A.Value)*sind(app.G.Value)     -sind(app.A.Value)*cosd(app.B.Value)*sind(app.G.Value) + cosd(app.A.Value)*cosd(app.G.Value)               sind(app.A.Value)*sind(app.B.Value) ;
%                                  -sind(app.B.Value)*cosd(app.G.Value)                                                              sind(app.B.Value)*sind(app.G.Value)                                                                        cosd(app.B.Value)];
%            app.C_UITable_Rotasyon.Data = RollPitchYawZXZ;
           beta = atan2d(sqrt(C_r(1,3)^2+C_r(2,3)^2), C_r(3,3));
           alfa = atan2d(C_r(1,3)/sind(beta),C_r(2,3)/-sind(beta));
           gama = atan2d(C_r(3,2)/sind(beta) , C_r(3,1)/-sind(beta));
           app.Alpha_Cikti.Value = alfa;
           app.Beta_Cikti.Value = beta;
           app.Gama_Cikti.Value = gama;
            end

            C_Teta = atan2d(sqrt((C_r(3,2)-C_r(2,3))^2+(C_r(1,3)-C_r(3,1))^2+(C_r(2,1)-C_r(1,2))^2),C_r(1,1)+C_r(2,2)+C_r(3,3)-1);0;0;
            K_Vektor = 1/2*sind(C_Teta)*[C_r(2,3)-C_r(3,2);
                                         C_r(1,3)-C_r(3,1);
                                         C_r(2,1)-C_r(1,2)];

            C_Teta = [C_Teta;"";""];

            Aci = [alfa, beta, gama]';
%             veri = [{K_Vektor},{C_Teta},{Aci}];
            

            app.C_UITable_2.Data = [ K_Vektor,  C_Teta,  round(Aci)];


%             app.C_UITable_2.Data =[app.C_UITable_2.Data; "K","θ","Angle Value"];
%             app.C_UITable_2.Data = [app.C_UITable_2.Data; string(K_Vektor),string(C_Teta);[];[];string(alfa)];
%             app.C_UITable_2.Data =[app.C_UITable_2.Data; "θ","",""];
%             app.C_UITable_2.Data =[app.C_UITable_2.Data; string(C_Teta),[],[]];
%             app.C_K1.Value = K_Vektor(1,1);
%             app.C_K2.Value = K_Vektor(2,1);
%             app.C_K3.Value = K_Vektor(3,1);
%             app.C_Teta_Text.Value = C_Teta;
%             app.UITable.Data = K_Vektor ;
        end

        % Button pushed function: B_UploadButton_2
        function B_UploadButton_2Pushed(app, event)
            T_alpha0 = app.B_TextArea_alpha0.Value*pi/180; %degreetoradian
            T_alpha1 = app.B_TextArea_alpha1.Value*pi/180;
            T_alpha2 = app.B_TextArea_alpha2.Value*pi/180;
            T_alpha3 = app.B_TextArea_alpha3.Value*pi/180;
            T_alpha4 = app.B_TextArea_alpha4.Value*pi/180;
            T_alpha5 = app.B_TextArea_alpha5.Value*pi/180;

            T_a0 = app.B_TextArea_a0.Value;
            T_a1 = app.B_TextArea_a1.Value;
            T_a2 = app.B_TextArea_a2.Value;
            T_a3 = app.B_TextArea_a3.Value;
            T_a4 = app.B_TextArea_a4.Value;
            T_a5 = app.B_TextArea_a5.Value;

            T_d1 = app.B_TextArea_d1.Value;
            T_d2 = app.B_TextArea_d2.Value;
            T_d3 = app.B_TextArea_d3.Value;
            T_d4 = app.B_TextArea_d4.Value;
            T_d5 = app.B_TextArea_d5.Value;
            T_d6 = app.B_TextArea_d6.Value;

            T_theta1 = app.B_TextArea_theta1.Value*pi/180;
            T_theta2 = app.B_TextArea_theta2.Value*pi/180;
            T_theta3 = app.B_TextArea_theta3.Value*pi/180;
            T_theta4 = app.B_TextArea_theta4.Value*pi/180;
            T_theta5 = app.B_TextArea_theta5.Value*pi/180;
            T_theta6 = app.B_TextArea_theta6.Value*pi/180;
            
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %DH tablosu       alpha        a          d         theta
            
            T_dhtablosu = [   T_alpha0       T_a0	      T_d1        T_theta1;
                              T_alpha1       T_a1	      T_d2        T_theta2;
                              T_alpha2       T_a2	      T_d3        T_theta3;
                              T_alpha3       T_a3	      T_d4        T_theta4;
                              T_alpha4       T_a4	      T_d5        T_theta5;
                              T_alpha5       T_a5	      T_d6        T_theta6 ];
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            %Transformasyon matrislerinin hesaplanması
            
            for i=1:6
                T_Transformasyonmatris{i}=[        cos(T_dhtablosu(i,4))                         -sin(T_dhtablosu(i,4))                           0                               T_dhtablosu(i,2);
                                               sin(T_dhtablosu(i,4))*cos(T_dhtablosu(i,1))      cos(T_dhtablosu(i,4))*cos(T_dhtablosu(i,1))      -sin(T_dhtablosu(i,1))         -sin(T_dhtablosu(i,1))*T_dhtablosu(i,3);
                                               sin(T_dhtablosu(i,4))*sin(T_dhtablosu(i,1))      cos(T_dhtablosu(i,4))*sin(T_dhtablosu(i,1))       cos(T_dhtablosu(i,1))          cos(T_dhtablosu(i,1))*T_dhtablosu(i,3);
                                                         0                                            0                                    0                                      1];
            end
            
            T_Transformasyonmatris{1};
            T_Transformasyonmatris{2};
            T_Transformasyonmatris{3};
            T_Transformasyonmatris{4};
            T_Transformasyonmatris{5};
            T_Transformasyonmatris{6};


            %Transformasyon matrislerinin hesaplanması
            T_Transformasyonmatris01 = T_Transformasyonmatris{1};
            T_Transformasyonmatris02 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2};
            T_Transformasyonmatris03 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3};
            T_Transformasyonmatris04 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3}*T_Transformasyonmatris{4};
            T_Transformasyonmatris05 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3}*T_Transformasyonmatris{4}*T_Transformasyonmatris{5};


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            %İleri kinematik transformasyon matrisi
            T_Transformasyonmatris06 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3}*T_Transformasyonmatris{4}*T_Transformasyonmatris{5}*T_Transformasyonmatris{6};
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
%           Roll Pitch Yaw
            RollPitchYaw = [cosd(app.B_EditField_A_Roll.Value)* cosd(app.B_EditField_B_Pitch.Value)   cosd(app.B_EditField_A_Roll.Value)*sind(app.B_EditField_B_Pitch.Value)*sind(app.B_EditField_C_Yaw.Value)-sind(app.B_EditField_A_Roll.Value)*cosd(app.B_EditField_C_Yaw.Value)   cosd(app.B_EditField_A_Roll.Value)*sind(app.B_EditField_B_Pitch.Value)*cosd(app.B_EditField_C_Yaw.Value) + sind(app.B_EditField_A_Roll.Value)*sind(app.B_EditField_C_Yaw.Value);
                            sind(app.B_EditField_A_Roll.Value)* cosd(app.B_EditField_B_Pitch.Value)   sind(app.B_EditField_A_Roll.Value)*sind(app.B_EditField_B_Pitch.Value)*sind(app.B_EditField_C_Yaw.Value)+cosd(app.B_EditField_A_Roll.Value)*cosd(app.B_EditField_C_Yaw.Value)   sind(app.B_EditField_A_Roll.Value)*sind(app.B_EditField_B_Pitch.Value)*cosd(app.B_EditField_C_Yaw.Value) - cosd(app.B_EditField_A_Roll.Value)*sind(app.B_EditField_C_Yaw.Value);
                            -sind(app.B_EditField_B_Pitch.Value)                                       cosd(app.B_EditField_B_Pitch.Value)*sind(app.B_EditField_C_Yaw.Value)                                                                                                           cosd(app.B_EditField_B_Pitch.Value)*cosd(app.B_EditField_C_Yaw.Value)];
            
            app.B_UITable_RollPitchYaw.Data = RollPitchYaw;

            if app.B_FabrikButton.Value == 1

            tolerance = 1;
            max_iterations = 1e7;
                    target_position = [app.B_XKonumuEditField.Value app.B_YKonumuEditField.Value app.B_ZKonumuEditField.Value];
                    joint_lengths = T_dhtablosu(:,3);
                    num_joints = length(joint_lengths);
                    joint_positions = zeros(num_joints, 3);
                    for iteration = 1:max_iterations
                        % 1. Geriye doğru (end-effector hedef konuma hareket eder)
                        joint_positions(end, :) = target_position;
                        for i = num_joints - 1:-1:1
                            vec_to_target = joint_positions(i + 1, :) - joint_positions(i, :);
                            joint_positions(i, :) = joint_positions(i + 1, :) - (joint_lengths(i) / norm(vec_to_target)) * vec_to_target;
                        end
                        % 2. İleri yönde (başlangıç konumuna hareket eder)
                        joint_positions(1, :) = zeros(1, 3);
                        for i = 1:num_joints - 1
                            vec_to_target = joint_positions(i + 1, :) - joint_positions(i, :);
                            joint_positions(i + 1, :) = joint_positions(i, :) + (joint_lengths(i) / norm(vec_to_target)) * vec_to_target;
                        end
                        error = norm(joint_positions(end, :) - target_position);
                        if error < tolerance
                            break;
                        end
                    end
                    end_effector_position = joint_positions(end, :);
                    orientation = atan2(norm(cross(joint_positions(end, :), joint_positions(2, :))), dot(joint_positions(end, :), joint_positions(2, :)));
                    orientation = orientation*180/pi;
                    app.B_Ters_Kinematik.Data = [end_effector_position;  orientation, "",""];

            elseif app.B_NewtonRapsonButton.Value == 1

                for i=1:6
                T_Transformasyonmatris{i}=[        cos(T_dhtablosu(i,4))                         -sin(T_dhtablosu(i,4))                           0                               T_dhtablosu(i,2);
                    sin(T_dhtablosu(i,4))*cos(T_dhtablosu(i,1))      cos(T_dhtablosu(i,4))*cos(T_dhtablosu(i,1))      -sin(T_dhtablosu(i,1))         -sin(T_dhtablosu(i,1))*T_dhtablosu(i,3);
                    sin(T_dhtablosu(i,4))*sin(T_dhtablosu(i,1))      cos(T_dhtablosu(i,4))*sin(T_dhtablosu(i,1))       cos(T_dhtablosu(i,1))          cos(T_dhtablosu(i,1))*T_dhtablosu(i,3);
                    0                                            0                                    0                                      1];
               end
            
            T_Transformasyonmatris{1};
            T_Transformasyonmatris{2};
            T_Transformasyonmatris{3};
            T_Transformasyonmatris{4};
            T_Transformasyonmatris{5};
            T_Transformasyonmatris{6};


            %Transformasyon matrislerinin hesaplanması
            T_Transformasyonmatris01 = T_Transformasyonmatris{1};
            T_Transformasyonmatris02 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2};
            T_Transformasyonmatris03 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3};
            T_Transformasyonmatris04 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3}*T_Transformasyonmatris{4};
            T_Transformasyonmatris05 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3}*T_Transformasyonmatris{4}*T_Transformasyonmatris{5};


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            %İleri kinematik transformasyon matrisi
            T_Transformasyonmatris06 = T_Transformasyonmatris{1}*T_Transformasyonmatris{2}*T_Transformasyonmatris{3}*T_Transformasyonmatris{4}*T_Transformasyonmatris{5}*T_Transformasyonmatris{6};


                tolerance = 1;
                max_iterations = 100000;
                target_position = [app.B_XKonumuEditField.Value app.B_YKonumuEditField.Value app.B_ZKonumuEditField.Value];
                initial_joint_angles = randn(1,6);
                iteration = 0;
                    while iteration < max_iterations
                        T_dhtablosu(:,4) = toDegrees("radians",initial_joint_angles);
                        current_pose = T_Transformasyonmatris06;
                        error = norm(target_position - current_pose(1:3,4));
                        if error < tolerance
                            break;
                        end


                 %Jakobiyen Hesaplama
            T_z11 = [0;0;1];
            T_z22 = [0;0;1];
            T_z33 = [0;0;1];
            T_z44 = [0;0;1];
            T_z55 = [0;0;1];
            T_z66 = [0;0;1];


            T_Rotasyon1 = T_Transformasyonmatris01(1:3,1:3);
            T_Rotasyon2 = T_Transformasyonmatris02(1:3,1:3);
            T_Rotasyon3 = T_Transformasyonmatris03(1:3,1:3);
            T_Rotasyon4 = T_Transformasyonmatris04(1:3,1:3);
            T_Rotasyon5 = T_Transformasyonmatris05(1:3,1:3);
            T_Rotasyon6 = T_Transformasyonmatris06(1:3,1:3);


            T_z01 = T_Rotasyon1 * T_z11;
            T_z02 = T_Rotasyon2 * T_z22;
            T_z03 = T_Rotasyon3 * T_z33;
            T_z04 = T_Rotasyon4 * T_z44;
            T_z05 = T_Rotasyon5 * T_z55;
            T_z06 = T_Rotasyon6 * T_z66;
            
            
            T_p01 = T_Transformasyonmatris01(1:3,4);
            T_p02 = T_Transformasyonmatris02(1:3,4);
            T_p03 = T_Transformasyonmatris03(1:3,4);
            T_p04 = T_Transformasyonmatris04(1:3,4);
            T_p05 = T_Transformasyonmatris05(1:3,4);
            T_p06 = T_Transformasyonmatris06(1:3,4);

            
            T_p08_01 = T_p06 - T_p01;
            T_p08_02 = T_p06 - T_p02;
            T_p08_03 = T_p06 - T_p03;
            T_p08_04 = T_p06 - T_p04;
            T_p08_05 = T_p06 - T_p05;
            T_p08_06 = T_p06 - T_p06;


                  T_Jakobiyen01 = [T_z01(2,1)*T_p08_01(3,1) - T_z01(3,1)*T_p08_01(2,1);
                                   T_z01(3,1)*T_p08_01(1,1) - T_z01(1,1)*T_p08_01(3,1);
                                   T_z01(1,1)*T_p08_01(2,1) - T_z01(2,1)*T_p08_01(1,1);T_z01];
                    
                  T_Jakobiyen02 = [T_z02(2,1)*T_p08_02(3,1) - T_z02(3,1)*T_p08_02(2,1);
                                 T_z02(3,1)*T_p08_02(1,1) - T_z02(1,1)*T_p08_02(3,1);
                                 T_z02(1,1)*T_p08_02(2,1) - T_z02(2,1)*T_p08_02(1,1);T_z02];
                    
                  T_Jakobiyen03 = [T_z03(2,1)*T_p08_03(3,1) - T_z03(3,1)*T_p08_03(2,1);
                                 T_z03(3,1)*T_p08_03(1,1) - T_z03(1,1)*T_p08_03(3,1);
                                 T_z03(1,1)*T_p08_03(2,1) - T_z03(2,1)*T_p08_03(1,1);T_z03];
                    
                  T_Jakobiyen04 = [T_z04(2,1)*T_p08_04(3,1) - T_z04(3,1)*T_p08_04(2,1);
                                 T_z04(3,1)*T_p08_04(1,1) - T_z04(1,1)*T_p08_04(3,1);
                                 T_z04(1,1)*T_p08_04(2,1) - T_z04(2,1)*T_p08_04(1,1);T_z04];
                   
                  T_Jakobiyen05 = [T_z05(2,1)*T_p08_05(3,1) - T_z05(3,1)*T_p08_05(2,1);
                                 T_z05(3,1)*T_p08_05(1,1) - T_z05(1,1)*T_p08_05(3,1);
                                 T_z05(1,1)*T_p08_05(2,1) - T_z05(2,1)*T_p08_05(1,1);T_z05];
                    
                  T_Jakobiyen06 = [T_z06(2,1)*T_p08_06(3,1) - T_z06(3,1)*T_p08_06(2,1);
                                 T_z06(3,1)*T_p08_06(1,1) - T_z06(1,1)*T_p08_06(3,1);
                                 T_z06(1,1)*T_p08_06(2,1) - T_z06(2,1)*T_p08_06(1,1);T_z06];
                    

                   T_Jakobiyen = [T_Jakobiyen01 T_Jakobiyen02 T_Jakobiyen03 T_Jakobiyen04 T_Jakobiyen05 T_Jakobiyen06];

                   delta_x = target_position - current_pose(1:3);
                        delta_theta = pinv(T_Jakobiyen) * [delta_x T_z06']';
                        initial_joint_angles = initial_joint_angles + delta_theta';
                        iteration = iteration + 1;
                    end
                    end_effector_position = delta_x;
                    initial_joint_angles = mod(initial_joint_angles,360);

                    app.B_Ters_Kinematik.Data = [end_effector_position,"","","";  initial_joint_angles];


            end


        end

        % Button pushed function: A_UploadButton_3
        function A_UploadButton_3Pushed(app, event)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Atamalar

            
            TA_alpha0 = app.A_TextArea_alpha0.Value*pi/180; %degreetoradian
            TA_alpha1 = app.A_TextArea_alpha1.Value*pi/180;
            TA_alpha2 = app.A_TextArea_alpha2.Value*pi/180;
            TA_alpha3 = app.A_TextArea_alpha3.Value*pi/180;
            TA_alpha4 = app.A_TextArea_alpha4.Value*pi/180;
            TA_alpha5 = app.A_TextArea_alpha5.Value*pi/180;
            TA_alpha6 = app.A_TextArea_alpha6.Value*pi/180;
            TA_alpha7 = app.A_TextArea_alpha7.Value*pi/180;

            TA_a0 = app.A_TextArea_a0.Value;
            TA_a1 = app.A_TextArea_a1.Value;
            TA_a2 = app.A_TextArea_a2.Value;
            TA_a3 = app.A_TextArea_a3.Value;
            TA_a4 = app.A_TextArea_a4.Value;
            TA_a5 = app.A_TextArea_a5.Value;
            TA_a6 = app.A_TextArea_a6.Value;
            TA_a7 = app.A_TextArea_a7.Value;

            TA_d1 = app.A_TextArea_d1.Value;
            TA_d2 = app.A_TextArea_d2.Value;
            TA_d3 = app.A_TextArea_d3.Value;
            TA_d4 = app.A_TextArea_d4.Value;
            TA_d5 = app.A_TextArea_d5.Value;
            TA_d6 = app.A_TextArea_d6.Value;
            TA_d7 = app.A_TextArea_d7.Value;
            TA_d8 = app.A_TextArea_d8.Value;

            TA_theta1 = app.A_TextArea_theta1.Value*pi/180;
            TA_theta2 = app.A_TextArea_theta2.Value*pi/180;
            TA_theta3 = app.A_TextArea_theta3.Value*pi/180;
            TA_theta4 = app.A_TextArea_theta4.Value*pi/180;
            TA_theta5 = app.A_TextArea_theta5.Value*pi/180;
            TA_theta6 = app.A_TextArea_theta6.Value*pi/180;
            TA_theta7 = app.A_TextArea_theta7.Value*pi/180;
            TA_theta8 = app.A_TextArea_theta8.Value*pi/180;
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %DH tablosu       alpha        a          d         theta
            
            TA_dhtablosu = [   TA_alpha0       TA_a0	      TA_d1        TA_theta1;
                              TA_alpha1       TA_a1	      TA_d2        TA_theta2;
                              TA_alpha2       TA_a2	      TA_d3        TA_theta3;
                              TA_alpha3       TA_a3	      TA_d4        TA_theta4;
                              TA_alpha4       TA_a4	      TA_d5        TA_theta5;
                              TA_alpha5       TA_a5	      TA_d6        TA_theta6;
                              TA_alpha6       TA_a6	      TA_d7        TA_theta7;
                              TA_alpha7       TA_a7	      TA_d8        TA_theta8   ];
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            %Transformasyon matrislerinin hesaplanması
            
%             for i=1:8
%                 TA_Transformasyonmatris{i}=[        cos(TA_dhtablosu(i,4))                         -sin(TA_dhtablosu(i,4))                           0                               TA_dhtablosu(i,2);
%                     sin(TA_dhtablosu(i,4))*cos(TA_dhtablosu(i,1))      cos(TA_dhtablosu(i,4))*cos(TA_dhtablosu(i,1))      -sin(TA_dhtablosu(i,1))         -sin(TA_dhtablosu(i,1))*TA_dhtablosu(i,3);
%                     sin(TA_dhtablosu(i,4))*sin(TA_dhtablosu(i,1))      cos(TA_dhtablosu(i,4))*sin(TA_dhtablosu(i,1))       cos(TA_dhtablosu(i,1))          cos(TA_dhtablosu(i,1))*TA_dhtablosu(i,3);
%                     0                                            0                                    0                                      1];
%             end
            
%             TA_Transformasyonmatris{1};
%             TA_Transformasyonmatris{2};
%             TA_Transformasyonmatris{3};
%             TA_Transformasyonmatris{4};
%             TA_Transformasyonmatris{5};
%             TA_Transformasyonmatris{6};
%             TA_Transformasyonmatris{7};
%             TA_Transformasyonmatris{8};
%             %Transformasyon matrislerinin hesaplanması
%             TA_Transformasyonmatris01 = TA_Transformasyonmatris{1};
%             TA_Transformasyonmatris02 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2};
%             TA_Transformasyonmatris03 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3};
%             TA_Transformasyonmatris04 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4};
%             TA_Transformasyonmatris05 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5};
%             TA_Transformasyonmatris06 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5}*TA_Transformasyonmatris{6};
%             TA_Transformasyonmatris07 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5}*TA_Transformasyonmatris{6}*TA_Transformasyonmatris{7};
% 
%             
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
%             %İleri kinematik transformasyon matrisi
%             
%             TA_Transformasyonmatris08=TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5}*TA_Transformasyonmatris{6}*TA_Transformasyonmatris{7}*TA_Transformasyonmatris{8};
            
             if app.A_FabrikButton.Value == 1

            tolerance = 1;
            max_iterations = 1e7;
                    target_position = [app.A_XKonumuEditField.Value app.A_YKonumuEditField.Value app.A_ZKonumuEditField.Value];
                    joint_lengths = TA_dhtablosu(:,3);
                    num_joints = length(joint_lengths);
                    joint_positions = zeros(num_joints, 3);
                    for iteration = 1:max_iterations
                        % 1. Geriye doğru (end-effector hedef konuma hareket eder)
                        joint_positions(end, :) = target_position;
                        for i = num_joints - 1:-1:1
                            vec_to_target = joint_positions(i + 1, :) - joint_positions(i, :);
                            joint_positions(i, :) = joint_positions(i + 1, :) - (joint_lengths(i) / norm(vec_to_target)) * vec_to_target;
                        end
                        % 2. İleri yönde (başlangıç konumuna hareket eder)
                        joint_positions(1, :) = zeros(1, 3);
                        for i = 1:num_joints - 1
                            vec_to_target = joint_positions(i + 1, :) - joint_positions(i, :);
                            joint_positions(i + 1, :) = joint_positions(i, :) + (joint_lengths(i) / norm(vec_to_target)) * vec_to_target;
                        end
                        error = norm(joint_positions(end, :) - target_position);
                        if error < tolerance
                            break;
                        end
                    end
                    end_effector_position = joint_positions(end, :);
                    orientation = atan2(norm(cross(joint_positions(end, :), joint_positions(2, :))), dot(joint_positions(end, :), joint_positions(2, :)));
                    orientation = orientation*180/pi;
                    app.A_Ters_Kinematik.Data = [end_effector_position;  orientation, "",""];
               elseif app.A_NewtonRapsonButton.Value == 1

                    for i=1:8
                        TA_Transformasyonmatris{i}=[        cos(TA_dhtablosu(i,4))                         -sin(TA_dhtablosu(i,4))                           0                               TA_dhtablosu(i,2);
                            sin(TA_dhtablosu(i,4))*cos(TA_dhtablosu(i,1))      cos(TA_dhtablosu(i,4))*cos(TA_dhtablosu(i,1))      -sin(TA_dhtablosu(i,1))         -sin(TA_dhtablosu(i,1))*TA_dhtablosu(i,3);
                            sin(TA_dhtablosu(i,4))*sin(TA_dhtablosu(i,1))      cos(TA_dhtablosu(i,4))*sin(TA_dhtablosu(i,1))       cos(TA_dhtablosu(i,1))          cos(TA_dhtablosu(i,1))*TA_dhtablosu(i,3);
                            0                                            0                                    0                                      1];
                    end
                    TA_Transformasyonmatris{1};
                    TA_Transformasyonmatris{2};
                    TA_Transformasyonmatris{3};
                    TA_Transformasyonmatris{4};
                    TA_Transformasyonmatris{5};
                    TA_Transformasyonmatris{6};
                    TA_Transformasyonmatris{7};
                    TA_Transformasyonmatris{8};
                    %Transformasyon matrislerinin hesaplanması
                    TA_Transformasyonmatris01 = TA_Transformasyonmatris{1};
                    TA_Transformasyonmatris02 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2};
                    TA_Transformasyonmatris03 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3};
                    TA_Transformasyonmatris04 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4};
                    TA_Transformasyonmatris05 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5};
                    TA_Transformasyonmatris06 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5}*TA_Transformasyonmatris{6};
                    TA_Transformasyonmatris07 = TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5}*TA_Transformasyonmatris{6}*TA_Transformasyonmatris{7};

            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
                    %İleri kinematik transformasyon matrisi
                    
                    TA_Transformasyonmatris08=TA_Transformasyonmatris{1}*TA_Transformasyonmatris{2}*TA_Transformasyonmatris{3}*TA_Transformasyonmatris{4}*TA_Transformasyonmatris{5}*TA_Transformasyonmatris{6}*TA_Transformasyonmatris{7}*TA_Transformasyonmatris{8};
                   
                    tolerance = 1;
                    max_iterations = 100000;
                    target_position = [app.A_XKonumuEditField.Value app.A_YKonumuEditField.Value app.A_ZKonumuEditField.Value];
                    initial_joint_angles = randn(1,8);
                    iteration = 0;
                        while iteration < max_iterations
                            TA_dhtablosu(:,4) = toDegrees("radians",initial_joint_angles);
                            current_pose = TA_Transformasyonmatris08;
                            error = norm(target_position - current_pose(1:3,4));
                            if error < tolerance
                                break;
                            end
                        

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%           %Jakobiyen Hesaplama
            TA_z11 = [0;0;1];
            TA_z22 = [0;0;1];
            TA_z33 = [0;0;1];
            TA_z44 = [0;0;1];
            TA_z55 = [0;0;1];
            TA_z66 = [0;0;1];
            TA_z77 = [0;0;1];
            TA_z88 = [0;0;1];
            
            
            TA_Rotasyon1 = TA_Transformasyonmatris01(1:3,1:3);
            TA_Rotasyon2 = TA_Transformasyonmatris02(1:3,1:3);
            TA_Rotasyon3 = TA_Transformasyonmatris03(1:3,1:3);
            TA_Rotasyon4 = TA_Transformasyonmatris04(1:3,1:3);
            TA_Rotasyon5 = TA_Transformasyonmatris05(1:3,1:3);
            TA_Rotasyon6 = TA_Transformasyonmatris06(1:3,1:3);
            TA_Rotasyon7 = TA_Transformasyonmatris07(1:3,1:3);
            TA_Rotasyon8 = TA_Transformasyonmatris08(1:3,1:3);
            
            
            TA_z01 = TA_Rotasyon1 * TA_z11;
            TA_z02 = TA_Rotasyon2 * TA_z22;
            TA_z03 = TA_Rotasyon3 * TA_z33;
            TA_z04 = TA_Rotasyon4 * TA_z44;
            TA_z05 = TA_Rotasyon5 * TA_z55;
            TA_z06 = TA_Rotasyon6 * TA_z66;
            TA_z07 = TA_Rotasyon7 * TA_z77;
            TA_z08 = TA_Rotasyon8 * TA_z88;
            
            TA_p01 = TA_Transformasyonmatris01(1:3,4);
            TA_p02 = TA_Transformasyonmatris02(1:3,4);
            TA_p03 = TA_Transformasyonmatris03(1:3,4);
            TA_p04 = TA_Transformasyonmatris04(1:3,4);
            TA_p05 = TA_Transformasyonmatris05(1:3,4);
            TA_p06 = TA_Transformasyonmatris06(1:3,4);
            TA_p07 = TA_Transformasyonmatris07(1:3,4);
            TA_p08 = TA_Transformasyonmatris08(1:3,4);
            
            TA_p08_01 = TA_p08 - TA_p01;
            TA_p08_02 = TA_p08 - TA_p02;
            TA_p08_03 = TA_p08 - TA_p03;
            TA_p08_04 = TA_p08 - TA_p04;
            TA_p08_05 = TA_p08 - TA_p05;
            TA_p08_06 = TA_p08 - TA_p06;
            TA_p08_07 = TA_p08 - TA_p07;
            TA_p08_08 = TA_p08 - TA_p08;
              
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
             %First Axis
            if app.A_First_Axis.Value == 1
                TA_Jakobiyen01 = [TA_z01(2,1)*TA_p08_01(3,1) - TA_z01(3,1)*TA_p08_01(2,1);
                                 TA_z01(3,1)*TA_p08_01(1,1) - TA_z01(1,1)*TA_p08_01(3,1);
                                 TA_z01(1,1)*TA_p08_01(2,1) - TA_z01(2,1)*TA_p08_01(1,1); TA_Rotasyon1(1:3,3)];
                
            elseif app.A_First_Axis.Value == 2
                TA_Jakobiyen01 = [A_Transformasyonmatris01(1:3,3);0;0;0];
                
            elseif app.A_First_Axis.Value == 3
                TA_Jakobiyen01 = [0;0;0;0;0;0];
                
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Second Axis
            if app.A_Second_Axis.Value == 1
                TA_Jakobiyen02 = [TA_z02(2,1)*TA_p08_02(3,1) - TA_z02(3,1)*TA_p08_02(2,1);
                                 TA_z02(3,1)*TA_p08_02(1,1) - TA_z02(1,1)*TA_p08_02(3,1);
                                 TA_z02(1,1)*TA_p08_02(2,1) - TA_z02(2,1)*TA_p08_02(1,1);TA_Rotasyon2(1:3,3)];
            elseif app.A_Second_Axis.Value == 2
                TA_Jakobiyen02 = [A_Transformasyonmatris02(1:3,3);0;0;0];
            elseif app.A_Second_Axis.Value == 3
                TA_Jakobiyen02 = [0;0;0;0;0;0];
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Third Axis
            if app.A_Third_Axis.Value == 1
                TA_Jakobiyen03 = [TA_z03(2,1)*TA_p08_03(3,1) - TA_z03(3,1)*TA_p08_03(2,1);
                                 TA_z03(3,1)*TA_p08_03(1,1) - TA_z03(1,1)*TA_p08_03(3,1);
                                 TA_z03(1,1)*TA_p08_03(2,1) - TA_z03(2,1)*TA_p08_03(1,1);TA_Rotasyon3(1:3,3)];
            elseif app.A_Third_Axis.Value == 2
                TA_Jakobiyen03 = [A_Transformasyonmatris03(1:3,3);0;0;0];
            elseif app.A_Third_Axis.Value == 3
                TA_Jakobiyen03 = [0;0;0;0;0;0];
            end            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Fourth Axis
            if app.A_Fourth_Axis.Value == 1
                TA_Jakobiyen04 = [TA_z04(2,1)*TA_p08_04(3,1) - TA_z04(3,1)*TA_p08_04(2,1);
                                 TA_z04(3,1)*TA_p08_04(1,1) - TA_z04(1,1)*TA_p08_04(3,1);
                                 TA_z04(1,1)*TA_p08_04(2,1) - TA_z04(2,1)*TA_p08_04(1,1);TA_Rotasyon4(1:3,3)];
            elseif app.A_Fourth_Axis.Value == 2
                TA_Jakobiyen04 = [A_Transformasyonmatris04(1:3,3);0;0;0];
            elseif app.A_Fourth_Axis.Value == 3
                TA_Jakobiyen04 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Fifth Axis
            if app.A_Fifth_Axis.Value == 1
                TA_Jakobiyen05 = [TA_z05(2,1)*TA_p08_05(3,1) - TA_z05(3,1)*TA_p08_05(2,1);
                                 TA_z05(3,1)*TA_p08_05(1,1) - TA_z05(1,1)*TA_p08_05(3,1);
                                 TA_z05(1,1)*TA_p08_05(2,1) - TA_z05(2,1)*TA_p08_05(1,1);TA_Rotasyon5(1:3,3)];
            elseif app.A_Fifth_Axis.Value == 2
                TA_Jakobiyen05 = [A_Transformasyonmatris05(1:3,3);0;0;0];
            elseif app.A_Fifth_Axis.Value == 3
                TA_Jakobiyen05 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Sixth_Axis 
            if app.A_Sixth_Axis.Value == 1
                TA_Jakobiyen06 = [TA_z06(2,1)*TA_p08_06(3,1) - TA_z06(3,1)*TA_p08_06(2,1);
                                 TA_z06(3,1)*TA_p08_06(1,1) - TA_z06(1,1)*TA_p08_06(3,1);
                                 TA_z06(1,1)*TA_p08_06(2,1) - TA_z06(2,1)*TA_p08_06(1,1);TA_Rotasyon6(1:3,3)];
            elseif app.A_Sixth_Axis.Value == 2
                TA_Jakobiyen06 = [A_Transformasyonmatris06(1:3,3);0;0;0];
            elseif app.A_Sixth_Axis.Value == 3
                TA_Jakobiyen06 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Seventh_Axis
            if app.A_Seventh_Axis.Value == 1
                TA_Jakobiyen07 = [TA_z07(2,1)*TA_p08_07(3,1) - TA_z07(3,1)*TA_p08_07(2,1);
                                 TA_z07(3,1)*TA_p08_07(1,1) - TA_z07(1,1)*TA_p08_07(3,1);
                                 TA_z07(1,1)*TA_p08_07(2,1) - TA_z07(2,1)*TA_p08_07(1,1);TA_Rotasyon7(1:3,3)];
            elseif app.A_Seventh_Axis.Value == 2
                TA_Jakobiyen07 = [A_Transformasyonmatris07(1:3,3);0;0;0];
            elseif app.A_Seventh_Axis.Value == 3
                TA_Jakobiyen07 = [0;0;0;0;0;0];
            end        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Eighth_Axis
            if app.A_Eighth_Axis.Value == 1
                TA_Jakobiyen08 = [TA_z08(2,1)*TA_p08_08(3,1) - TA_z08(3,1)*TA_p08_08(2,1);
                                 TA_z08(3,1)*TA_p08_08(1,1) - TA_z08(1,1)*TA_p08_08(3,1);
                                 TA_z08(1,1)*TA_p08_08(2,1) - TA_z08(2,1)*TA_p08_08(1,1);TA_Rotasyon8(1:3,3)];
            elseif app.A_Eighth_Axis.Value == 2
                TA_Jakobiyen08 = [A_Transformasyonmatris08(1:3,3);0;0;0];
            elseif app.A_Eighth_Axis.Value == 3
                TA_Jakobiyen08 = [0;0;0;0;0;0];
            end                    
            
            
            TA_Jakobiyen = [TA_Jakobiyen01 TA_Jakobiyen02 TA_Jakobiyen03 TA_Jakobiyen04 TA_Jakobiyen05 TA_Jakobiyen06 TA_Jakobiyen07 TA_Jakobiyen08];
            
            delta_x = target_position - current_pose(1:3);
                        delta_theta = pinv(TA_Jakobiyen) * [delta_x TA_z08']';
                        initial_joint_angles = initial_joint_angles + delta_theta';
                        iteration = iteration + 1;
                    end
                    end_effector_position = delta_x;
                    initial_joint_angles = mod(initial_joint_angles,360);

                    app.A_Ters_Kinematik.Data = [end_effector_position,"","","","","";  initial_joint_angles];

             end


        end

        % Value changed function: EditField_Min_Axes1
        function EditField_Min_Axes1ValueChanged(app, event)
            app.A_Theta1_Slider1.Limits(1,1) = app.EditField_Min_Axes1.Value;
        end

        % Value changed function: EditField_Max_Axes1
        function EditField_Max_Axes1ValueChanged(app, event)
            app.A_Theta1_Slider1.Limits(1,2) = app.EditField_Max_Axes1.Value;            
        end

        % Value changed function: EditField_Min_Axes2
        function EditField_Min_Axes2ValueChanged(app, event)
            app.A_Theta2_Slider2.Limits(1,1) = app.EditField_Min_Axes2.Value;            
        end

        % Value changed function: EditField_Max_Axes2
        function EditField_Max_Axes2ValueChanged(app, event)
             app.A_Theta2_Slider2.Limits(1,2) = app.EditField_Max_Axes2.Value;      
        end

        % Value changed function: EditField_Min_Axes3
        function EditField_Min_Axes3ValueChanged(app, event)
            app.A_Theta3_Slider3.Limits(1,1) = app.EditField_Min_Axes3.Value;            
        end

        % Value changed function: EditField_Max_Axes3
        function EditField_Max_Axes3ValueChanged(app, event)
            app.A_Theta3_Slider3.Limits(1,2) = app.EditField_Max_Axes3.Value;                
        end

        % Value changed function: EditField_Min_Axes4
        function EditField_Min_Axes4ValueChanged(app, event)
            app.A_Theta4_Slider4.Limits(1,1) = app.EditField_Min_Axes4.Value;             
        end

        % Value changed function: EditField_Max_Axes4
        function EditField_Max_Axes4ValueChanged(app, event)
            app.A_Theta4_Slider4.Limits(1,2) = app.EditField_Max_Axes4.Value;      
        end

        % Value changed function: EditField_Min_Axes5
        function EditField_Min_Axes5ValueChanged(app, event)
            app.A_Theta5_Slider5.Limits(1,1) = app.EditField_Min_Axes5.Value;            
        end

        % Value changed function: EditField_Max_Axes5
        function EditField_Max_Axes5ValueChanged(app, event)
            app.A_Theta5_Slider5.Limits(1,2) = app.EditField_Max_Axes5.Value;      
        end

        % Value changed function: EditField_Min_Axes6
        function EditField_Min_Axes6ValueChanged(app, event)
            app.A_Theta6_Slider6.Limits(1,1) = app.EditField_Min_Axes6.Value;             
        end

        % Value changed function: EditField_Max_Axes6
        function EditField_Max_Axes6ValueChanged(app, event)
            app.A_Theta6_Slider6.Limits(1,2) = app.EditField_Max_Axes6.Value;      
        end

        % Value changed function: EditField_Min_Axes7
        function EditField_Min_Axes7ValueChanged(app, event)
            app.A_Theta7_Slider7.Limits(1,1) = app.EditField_Min_Axes7.Value;             
        end

        % Value changed function: EditField_Max_Axes7
        function EditField_Max_Axes7ValueChanged(app, event)
            app.A_Theta7_Slider7.Limits(1,2) = app.EditField_Max_Axes7.Value;      
        end

        % Value changed function: EditField_Min_Axes8
        function EditField_Min_Axes8ValueChanged(app, event)
            app.A_Theta8_Slider8.Limits(1,1) = app.EditField_Min_Axes8.Value;             
        end

        % Value changed function: EditField_Max_Axes8
        function EditField_Max_Axes8ValueChanged(app, event)
            app.A_Theta8_Slider8.Limits(1,2) = app.EditField_Max_Axes8.Value; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GENERALTab = uitab(app.TabGroup);
            app.GENERALTab.Title = 'GENERAL';
            app.A_UIAxes1 = uiaxes(app.GENERALTab);
            title(app.A_UIAxes1, 'Robotics Axes')
            xlabel(app.A_UIAxes1, 'X')
            ylabel(app.A_UIAxes1, 'Y')
            zlabel(app.A_UIAxes1, 'Z')
            app.InverseKinematicsPanel_2 = uipanel(app.GENERALTab);
            app.InverseKinematicsPanel_2.Title = 'Inverse Kinematics';
            app.A_EditField_A_Roll = uieditfield(app.InverseKinematicsPanel_2, 'numeric');
            app.A_EditField_A_Roll.Value = 25;
            app.A_EditField_B_Pitch = uieditfield(app.InverseKinematicsPanel_2, 'numeric');
            app.A_EditField_B_Pitch.Value = -17;
            app.A_EditField_C_Yaw = uieditfield(app.InverseKinematicsPanel_2, 'numeric');
            app.A_EditField_C_Yaw.Value = 42;
            app.A_UploadButton_3 = uibutton(app.InverseKinematicsPanel_2, 'push');
            app.A_UploadButton_3.ButtonPushedFcn = createCallbackFcn(app, @A_UploadButton_3Pushed, true);
            app.A_UploadButton_3.Text = 'Inverse Kinematics';
            app.A_ZKonumuEditField = uieditfield(app.InverseKinematicsPanel_2, 'numeric');
            app.A_ZKonumuEditField.Value = 42;
            app.A_XKonumuEditField = uieditfield(app.InverseKinematicsPanel_2, 'numeric');
            app.A_XKonumuEditField.Value = 25;
            app.A_YKonumuEditField = uieditfield(app.InverseKinematicsPanel_2, 'numeric');
            app.A_YKonumuEditField.Value = -17;
            app.A_TersHesaplaButtonGroup = uibuttongroup(app.InverseKinematicsPanel_2);
            app.A_TersHesaplaButtonGroup.Title = 'Inverse Kinematics';
            app.A_FabrikButton = uiradiobutton(app.A_TersHesaplaButtonGroup);
            app.A_FabrikButton.Text = 'Fabrik';
            app.A_FabrikButton.Value = true;
            app.A_NewtonRapsonButton = uiradiobutton(app.A_TersHesaplaButtonGroup);
            app.A_NewtonRapsonButton.Text = 'Newton Rapson';
            app.RollLabel_2 = uilabel(app.InverseKinematicsPanel_2);
            app.RollLabel_2.Text = 'Roll';
            app.PitchLabel_2 = uilabel(app.InverseKinematicsPanel_2);
            app.PitchLabel_2.Text = 'Pitch';
            app.YawLabel_2 = uilabel(app.InverseKinematicsPanel_2);
            app.YawLabel_2.Text = 'Yaw';
            app.TargetXLabel_2 = uilabel(app.InverseKinematicsPanel_2);
            app.TargetXLabel_2.Text = 'Target X';
            app.TargetYLabel_2 = uilabel(app.InverseKinematicsPanel_2);
            app.TargetYLabel_2.Text = 'Target Y';
            app.TargetZLabel_2 = uilabel(app.InverseKinematicsPanel_2);
            app.TargetZLabel_2.Text = 'Target Z';
            app.A_Ters_Kinematik = uitable(app.InverseKinematicsPanel_2);
            app.A_Ters_Kinematik.ColumnName = {'Location'};
            app.A_Ters_Kinematik.RowName = {};
            app.DHTablePanel = uipanel(app.GENERALTab);
            app.DHTablePanel.Title = 'D-H Table';
            app.A_Label_2 = uilabel(app.DHTablePanel);
            app.A_Label_2.Text = 'θ';
            app.A_iLabel_2 = uilabel(app.DHTablePanel);
            app.A_iLabel_2.Text = 'i';
            app.A_iLabel = uilabel(app.DHTablePanel);
            app.A_iLabel.Text = 'i';
            app.A_dLabel = uilabel(app.DHTablePanel);
            app.A_dLabel.Text = 'd';
            app.A_i1Label_2 = uilabel(app.DHTablePanel);
            app.A_i1Label_2.Text = 'i-1';
            app.A_aLabel = uilabel(app.DHTablePanel);
            app.A_aLabel.Text = 'a';
            app.A_i1Label = uilabel(app.DHTablePanel);
            app.A_i1Label.Text = 'i-1';
            app.A_Label = uilabel(app.DHTablePanel);
            app.A_Label.Text = 'α';
            app.A_TextArea_alpha0 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_a0 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d1 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d1.Value = 262;
            app.A_TextArea_theta1 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta1.Limits = [-360 360];
            app.A_TextArea_theta1.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta1ValueChanged, true);
            app.A_TextArea_alpha1 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_alpha1.Value = 90;
            app.A_TextArea_a1 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d2 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d2.Value = 168;
            app.A_TextArea_theta2 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta2.Limits = [-360 360];
            app.A_TextArea_theta2.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta2ValueChanged, true);
            app.A_TextArea_alpha2 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_alpha2.Value = -90;
            app.A_TextArea_a2 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d3 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d3.Value = 730;
            app.A_TextArea_theta3 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta3.Limits = [-360 360];
            app.A_TextArea_theta3.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta3ValueChanged, true);
            app.A_TextArea_alpha3 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_alpha3.Value = 90;
            app.A_TextArea_a3 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d4 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d4.Value = -168;
            app.A_TextArea_theta4 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta4.Limits = [-360 360];
            app.A_TextArea_theta4.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta4ValueChanged, true);
            app.A_TextArea_theta4.Value = 90;
            app.A_TextArea_alpha4 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_alpha4.Value = -90;
            app.A_TextArea_a4 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d5 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta5 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta5.Limits = [-360 360];
            app.A_TextArea_theta5.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta5ValueChanged, true);
            app.A_TextArea_alpha5 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_a5 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d6 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d6.Value = 570;
            app.A_TextArea_theta6 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta6.Limits = [-360 360];
            app.A_TextArea_theta6.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta6ValueChanged, true);
            app.A_TextArea_alpha6 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_alpha6.Value = 90;
            app.A_TextArea_a6 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d7 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta7 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta7.Limits = [-360 360];
            app.A_TextArea_theta7.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta7ValueChanged, true);
            app.A_TextArea_theta7.Value = 90;
            app.A_TextArea_alpha7 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_alpha7.Value = -90;
            app.A_TextArea_a7 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d8 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_d8.Value = 173;
            app.A_TextArea_theta8 = uieditfield(app.DHTablePanel, 'numeric');
            app.A_TextArea_theta8.Limits = [-360 360];
            app.A_TextArea_theta8.ValueChangedFcn = createCallbackFcn(app, @A_TextArea_theta8ValueChanged, true);
            app.A_CALCULATEButton = uibutton(app.DHTablePanel, 'push');
            app.A_CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @A_CALCULATEButtonPushed, true);
            app.A_CALCULATEButton.Text = 'CALCULATE';
            app.A_Delete_Button = uibutton(app.DHTablePanel, 'push');
            app.A_Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @A_Delete_ButtonPushed, true);
            app.A_Delete_Button.Text = 'DELETE AXES';
            app.A_DRAWButton = uibutton(app.DHTablePanel, 'push');
            app.A_DRAWButton.ButtonPushedFcn = createCallbackFcn(app, @A_DRAWButtonPushed, true);
            app.A_DRAWButton.Text = 'DRAW';
            app.JointTypePanel = uipanel(app.GENERALTab);
            app.JointTypePanel.Title = 'Joint Type';
            app.A_EklemTipiButton1 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton1.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton1SelectionChanged, true);
            app.A_EklemTipiButton1.Title = '1. Joint Type';
            app.A_RevoluteButton_1 = uiradiobutton(app.A_EklemTipiButton1);
            app.A_RevoluteButton_1.Text = 'Revolute';
            app.A_RevoluteButton_1.Value = true;
            app.A_PrismatikButton_1 = uiradiobutton(app.A_EklemTipiButton1);
            app.A_PrismatikButton_1.Text = 'Prismatik';
            app.A_NoneButton_1 = uiradiobutton(app.A_EklemTipiButton1);
            app.A_NoneButton_1.Text = 'None';
            app.A_EklemTipiButton2 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton2.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton2SelectionChanged, true);
            app.A_EklemTipiButton2.Title = '2. Joint Type';
            app.A_RevoluteButton_2 = uiradiobutton(app.A_EklemTipiButton2);
            app.A_RevoluteButton_2.Text = 'Revolute';
            app.A_RevoluteButton_2.Value = true;
            app.A_PrismatikButton_2 = uiradiobutton(app.A_EklemTipiButton2);
            app.A_PrismatikButton_2.Text = 'Prismatik';
            app.A_NoneButton_2 = uiradiobutton(app.A_EklemTipiButton2);
            app.A_NoneButton_2.Text = 'None';
            app.A_EklemTipiButton3 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton3.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton3SelectionChanged, true);
            app.A_EklemTipiButton3.Title = '3. Joint Type';
            app.A_RevoluteButton_3 = uiradiobutton(app.A_EklemTipiButton3);
            app.A_RevoluteButton_3.Text = 'Revolute';
            app.A_RevoluteButton_3.Value = true;
            app.A_PrismatikButton_3 = uiradiobutton(app.A_EklemTipiButton3);
            app.A_PrismatikButton_3.Text = 'Prismatik';
            app.A_NoneButton_3 = uiradiobutton(app.A_EklemTipiButton3);
            app.A_NoneButton_3.Text = 'None';
            app.A_EklemTipiButton4 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton4.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton4SelectionChanged, true);
            app.A_EklemTipiButton4.Title = '4. Joint Type';
            app.A_RevoluteButton_4 = uiradiobutton(app.A_EklemTipiButton4);
            app.A_RevoluteButton_4.Text = 'Revolute';
            app.A_RevoluteButton_4.Value = true;
            app.A_PrismatikButton_4 = uiradiobutton(app.A_EklemTipiButton4);
            app.A_PrismatikButton_4.Text = 'Prismatik';
            app.A_NoneButton_4 = uiradiobutton(app.A_EklemTipiButton4);
            app.A_NoneButton_4.Text = 'None';
            app.A_EklemTipiButton5 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton5.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton5SelectionChanged, true);
            app.A_EklemTipiButton5.Title = '5. Joint Type';
            app.A_RevoluteButton_5 = uiradiobutton(app.A_EklemTipiButton5);
            app.A_RevoluteButton_5.Text = 'Revolute';
            app.A_RevoluteButton_5.Value = true;
            app.A_PrismatikButton_5 = uiradiobutton(app.A_EklemTipiButton5);
            app.A_PrismatikButton_5.Text = 'Prismatik';
            app.A_NoneButton_5 = uiradiobutton(app.A_EklemTipiButton5);
            app.A_NoneButton_5.Text = 'None';
            app.A_EklemTipiButton6 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton6.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton6SelectionChanged, true);
            app.A_EklemTipiButton6.Title = '6. Joint Type';
            app.A_RevoluteButton_6 = uiradiobutton(app.A_EklemTipiButton6);
            app.A_RevoluteButton_6.Text = 'Revolute';
            app.A_RevoluteButton_6.Value = true;
            app.A_PrismatikButton_6 = uiradiobutton(app.A_EklemTipiButton6);
            app.A_PrismatikButton_6.Text = 'Prismatik';
            app.A_NoneButton_6 = uiradiobutton(app.A_EklemTipiButton6);
            app.A_NoneButton_6.Text = 'None';
            app.A_EklemTipiButton7 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton7.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton7SelectionChanged, true);
            app.A_EklemTipiButton7.Title = '7. Joint Type';
            app.A_RevoluteButton_7 = uiradiobutton(app.A_EklemTipiButton7);
            app.A_RevoluteButton_7.Text = 'Revolute';
            app.A_RevoluteButton_7.Value = true;
            app.A_PrismatikButton_7 = uiradiobutton(app.A_EklemTipiButton7);
            app.A_PrismatikButton_7.Text = 'Prismatik';
            app.A_NoneButton_7 = uiradiobutton(app.A_EklemTipiButton7);
            app.A_NoneButton_7.Text = 'None';
            app.A_EklemTipiButton8 = uibuttongroup(app.JointTypePanel);
            app.A_EklemTipiButton8.SelectionChangedFcn = createCallbackFcn(app, @A_EklemTipiButton8SelectionChanged, true);
            app.A_EklemTipiButton8.Title = '8. Joint Type';
            app.A_RevoluteButton_8 = uiradiobutton(app.A_EklemTipiButton8);
            app.A_RevoluteButton_8.Text = 'Revolute';
            app.A_RevoluteButton_8.Value = true;
            app.A_PrismatikButton_8 = uiradiobutton(app.A_EklemTipiButton8);
            app.A_PrismatikButton_8.Text = 'Prismatik';
            app.A_NoneButton_8 = uiradiobutton(app.A_EklemTipiButton8);
            app.A_NoneButton_8.Text = 'None';
            app.A_First_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_First_Axis.Limits = [1 3];
            app.A_First_Axis.ValueDisplayFormat = '%.0f';
            app.A_First_Axis.ValueChangedFcn = createCallbackFcn(app, @A_First_AxisValueChanged, true);
            app.A_First_Axis.Value = 1;
            app.A_Second_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_Second_Axis.Limits = [1 3];
            app.A_Second_Axis.ValueChangedFcn = createCallbackFcn(app, @A_Second_AxisValueChanged, true);
            app.A_Second_Axis.Value = 1;
            app.A_Third_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_Third_Axis.Limits = [1 3];
            app.A_Third_Axis.ValueChangedFcn = createCallbackFcn(app, @A_Third_AxisValueChanged, true);
            app.A_Third_Axis.Value = 1;
            app.A_Fourth_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_Fourth_Axis.Limits = [1 3];
            app.A_Fourth_Axis.ValueChangedFcn = createCallbackFcn(app, @A_Fourth_AxisValueChanged, true);
            app.A_Fourth_Axis.Value = 1;
            app.A_Fifth_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_Fifth_Axis.Limits = [1 3];
            app.A_Fifth_Axis.ValueChangedFcn = createCallbackFcn(app, @A_Fifth_AxisValueChanged, true);
            app.A_Fifth_Axis.Value = 1;
            app.A_Sixth_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_Sixth_Axis.Limits = [1 3];
            app.A_Sixth_Axis.ValueChangedFcn = createCallbackFcn(app, @A_Sixth_AxisValueChanged, true);
            app.A_Sixth_Axis.Value = 1;
            app.A_Seventh_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_Seventh_Axis.Limits = [1 3];
            app.A_Seventh_Axis.ValueChangedFcn = createCallbackFcn(app, @A_Seventh_AxisValueChanged, true);
            app.A_Seventh_Axis.Value = 1;
            app.A_Eighth_Axis = uieditfield(app.JointTypePanel, 'numeric');
            app.A_Eighth_Axis.Limits = [1 3];
            app.A_Eighth_Axis.ValueChangedFcn = createCallbackFcn(app, @A_Eighth_AxisValueChanged, true);
            app.A_Eighth_Axis.Value = 1;
            app.SliderForAxisPanel = uipanel(app.GENERALTab);
            app.SliderForAxisPanel.Title = 'Slider For Axis';
            app.Axis2Label = uilabel(app.SliderForAxisPanel);
            app.Axis2Label.Text = 'Axis2';
            app.A_Theta2_Slider2 = uislider(app.SliderForAxisPanel);
            app.A_Theta2_Slider2.Limits = [-360 360];
            app.A_Theta2_Slider2.ValueChangingFcn = createCallbackFcn(app, @A_Theta2_Slider2ValueChanging, true);
            app.Axis3Label = uilabel(app.SliderForAxisPanel);
            app.Axis3Label.Text = 'Axis3';
            app.A_Theta3_Slider3 = uislider(app.SliderForAxisPanel);
            app.A_Theta3_Slider3.Limits = [-360 360];
            app.A_Theta3_Slider3.ValueChangingFcn = createCallbackFcn(app, @A_Theta3_Slider3ValueChanging, true);
            app.Axis4Label = uilabel(app.SliderForAxisPanel);
            app.Axis4Label.Text = 'Axis4';
            app.A_Theta4_Slider4 = uislider(app.SliderForAxisPanel);
            app.A_Theta4_Slider4.Limits = [-360 360];
            app.A_Theta4_Slider4.ValueChangingFcn = createCallbackFcn(app, @A_Theta4_Slider4ValueChanging, true);
            app.Axis5Label = uilabel(app.SliderForAxisPanel);
            app.Axis5Label.Text = 'Axis5';
            app.A_Theta5_Slider5 = uislider(app.SliderForAxisPanel);
            app.A_Theta5_Slider5.Limits = [-360 360];
            app.A_Theta5_Slider5.ValueChangingFcn = createCallbackFcn(app, @A_Theta5_Slider5ValueChanging, true);
            app.Axis6Label = uilabel(app.SliderForAxisPanel);
            app.Axis6Label.Text = 'Axis6';
            app.A_Theta6_Slider6 = uislider(app.SliderForAxisPanel);
            app.A_Theta6_Slider6.Limits = [-360 360];
            app.A_Theta6_Slider6.ValueChangingFcn = createCallbackFcn(app, @A_Theta6_Slider6ValueChanging, true);
            app.A_Theta6_Slider6.Value = 46.4162683653503;
            app.Axis7Label = uilabel(app.SliderForAxisPanel);
            app.Axis7Label.Text = 'Axis7';
            app.A_Theta7_Slider7 = uislider(app.SliderForAxisPanel);
            app.A_Theta7_Slider7.Limits = [-360 360];
            app.A_Theta7_Slider7.ValueChangingFcn = createCallbackFcn(app, @A_Theta7_Slider7ValueChanging, true);
            app.Axis8Label = uilabel(app.SliderForAxisPanel);
            app.Axis8Label.Text = 'Axis8';
            app.A_Theta8_Slider8 = uislider(app.SliderForAxisPanel);
            app.A_Theta8_Slider8.Limits = [-360 360];
            app.A_Theta8_Slider8.ValueChangingFcn = createCallbackFcn(app, @A_Theta8_Slider8ValueChanging, true);
            app.Axis1Label = uilabel(app.SliderForAxisPanel);
            app.Axis1Label.Text = 'Axis1';
            app.A_Theta1_Slider1 = uislider(app.SliderForAxisPanel);
            app.A_Theta1_Slider1.Limits = [-360 360];
            app.A_Theta1_Slider1.ValueChangingFcn = createCallbackFcn(app, @A_Theta1_Slider1ValueChanging, true);
            app.EditField_Min_Axes1 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes1.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes1ValueChanged, true);
            app.EditField_Max_Axes1 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes1.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes1ValueChanged, true);
            app.MinLabel = uilabel(app.SliderForAxisPanel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.SliderForAxisPanel);
            app.MaxLabel.Text = 'Max';
            app.MinLabel_2 = uilabel(app.SliderForAxisPanel);
            app.MinLabel_2.Text = 'Min';
            app.EditField_Min_Axes2 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes2.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes2ValueChanged, true);
            app.MaxLabel_2 = uilabel(app.SliderForAxisPanel);
            app.MaxLabel_2.Text = 'Max';
            app.EditField_Max_Axes2 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes2.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes2ValueChanged, true);
            app.EditField_Max_Axes3 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes3.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes3ValueChanged, true);
            app.MaxLabel_3 = uilabel(app.SliderForAxisPanel);
            app.MaxLabel_3.Text = 'Max';
            app.EditField_Min_Axes3 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes3.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes3ValueChanged, true);
            app.MinLabel_3 = uilabel(app.SliderForAxisPanel);
            app.MinLabel_3.Text = 'Min';
            app.MinLabel_4 = uilabel(app.SliderForAxisPanel);
            app.MinLabel_4.Text = 'Min';
            app.EditField_Min_Axes4 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes4.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes4ValueChanged, true);
            app.MaxLabel_4 = uilabel(app.SliderForAxisPanel);
            app.MaxLabel_4.Text = 'Max';
            app.EditField_Max_Axes4 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes4.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes4ValueChanged, true);
            app.EditField_Max_Axes8 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes8.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes8ValueChanged, true);
            app.MaxLabel_8 = uilabel(app.SliderForAxisPanel);
            app.MaxLabel_8.Text = 'Max';
            app.EditField_Min_Axes8 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes8.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes8ValueChanged, true);
            app.MinLabel_8 = uilabel(app.SliderForAxisPanel);
            app.MinLabel_8.Text = 'Min';
            app.MinLabel_7 = uilabel(app.SliderForAxisPanel);
            app.MinLabel_7.Text = 'Min';
            app.EditField_Min_Axes7 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes7.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes7ValueChanged, true);
            app.MaxLabel_7 = uilabel(app.SliderForAxisPanel);
            app.MaxLabel_7.Text = 'Max';
            app.EditField_Max_Axes7 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes7.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes7ValueChanged, true);
            app.EditField_Max_Axes6 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes6.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes6ValueChanged, true);
            app.MaxLabel_6 = uilabel(app.SliderForAxisPanel);
            app.MaxLabel_6.Text = 'Max';
            app.EditField_Min_Axes6 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes6.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes6ValueChanged, true);
            app.MinLabel_6 = uilabel(app.SliderForAxisPanel);
            app.MinLabel_6.Text = 'Min';
            app.MinLabel_5 = uilabel(app.SliderForAxisPanel);
            app.MinLabel_5.Text = 'Min';
            app.EditField_Min_Axes5 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Min_Axes5.ValueChangedFcn = createCallbackFcn(app, @EditField_Min_Axes5ValueChanged, true);
            app.MaxLabel_5 = uilabel(app.SliderForAxisPanel);
            app.MaxLabel_5.Text = 'Max';
            app.EditField_Max_Axes5 = uieditfield(app.SliderForAxisPanel, 'numeric');
            app.EditField_Max_Axes5.ValueChangedFcn = createCallbackFcn(app, @EditField_Max_Axes5ValueChanged, true);
            app.TablesPanel = uipanel(app.GENERALTab);
            app.TablesPanel.Title = 'Tables';
            app.A_UITable_Oryantasyon = uitable(app.TablesPanel);
            app.A_UITable_Oryantasyon.ColumnName = {'X'; 'Y'; 'Z'};
            app.A_UITable_Oryantasyon.RowName = {};
            app.A_UITable_Konum = uitable(app.TablesPanel);
            app.A_UITable_Konum.ColumnName = {'Location'};
            app.A_UITable_Konum.RowName = {};
            app.A_UITable_Jakobiyen = uitable(app.TablesPanel);
            app.A_UITable_Jakobiyen.ColumnName = {'Jakobian1'; 'Jakobian2'; 'Jakobian3'; 'Jakobian4'; 'Jakobian5'; 'Jakobian6'; 'Jakobian7'; 'Jakobian8'};
            app.A_UITable_Jakobiyen.RowName = {};
            app.OrientationLabel = uilabel(app.TablesPanel);
            app.OrientationLabel.Text = 'Orientation';
            app.PositionLabel = uilabel(app.TablesPanel);
            app.PositionLabel.Text = 'Position';
            app.JakobianLabel = uilabel(app.TablesPanel);
            app.JakobianLabel.Text = 'Jakobian';
            app.B_UITable_RollPitchYaw_2 = uitable(app.TablesPanel);
            app.B_UITable_RollPitchYaw_2.ColumnName = {'Roll X'; 'Pitch Y'; 'Yaw Z'};
            app.B_UITable_RollPitchYaw_2.RowName = {};
            app.RollPitchYawOrientationLabel_2 = uilabel(app.TablesPanel);
            app.RollPitchYawOrientationLabel_2.Text = 'RollPitchYaw Orientation';
            app.KUKA_KR8_R1420Tab = uitab(app.TabGroup);
            app.KUKA_KR8_R1420Tab.Title = 'KUKA_KR8_R1420';
            app.B_UIAxes1 = uiaxes(app.KUKA_KR8_R1420Tab);
            title(app.B_UIAxes1, 'Title')
            xlabel(app.B_UIAxes1, 'X')
            ylabel(app.B_UIAxes1, 'Y')
            zlabel(app.B_UIAxes1, 'Z')
            app.DHTablePanel_2 = uipanel(app.KUKA_KR8_R1420Tab);
            app.DHTablePanel_2.Title = 'D-H Table';
            app.B_UploadButton = uibutton(app.DHTablePanel_2, 'push');
            app.B_UploadButton.ButtonPushedFcn = createCallbackFcn(app, @B_UploadButtonPushed, true);
            app.B_UploadButton.Text = 'Upload Button';
            app.Label_3 = uilabel(app.DHTablePanel_2);
            app.Label_3.Text = 'α';
            app.aLabel_2 = uilabel(app.DHTablePanel_2);
            app.aLabel_2.Text = 'a';
            app.dLabel_2 = uilabel(app.DHTablePanel_2);
            app.dLabel_2.Text = 'd';
            app.Label_4 = uilabel(app.DHTablePanel_2);
            app.Label_4.Text = 'θ';
            app.i1Label_3 = uilabel(app.DHTablePanel_2);
            app.i1Label_3.Text = 'i-1';
            app.i1Label_4 = uilabel(app.DHTablePanel_2);
            app.i1Label_4.Text = 'i-1';
            app.iLabel_3 = uilabel(app.DHTablePanel_2);
            app.iLabel_3.Text = 'i';
            app.iLabel_4 = uilabel(app.DHTablePanel_2);
            app.iLabel_4.Text = 'i';
            app.B_TextArea_alpha0 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_alpha1 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_alpha1.Value = 90;
            app.B_TextArea_alpha2 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_alpha3 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_alpha3.Value = 90;
            app.B_TextArea_alpha4 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_alpha4.Value = 90;
            app.B_TextArea_alpha5 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_alpha5.Value = -90;
            app.B_TextArea_a0 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_a1 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_a1.Value = 150;
            app.B_TextArea_a2 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_a2.Value = 610;
            app.B_TextArea_a3 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_a3.Value = 200;
            app.B_TextArea_a4 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_a5 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_d1 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_d1.Value = 450;
            app.B_TextArea_d2 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_d3 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_d4 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_d4.Value = 630;
            app.B_TextArea_d5 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_d6 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_d6.Value = 80;
            app.B_TextArea_theta1 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_theta1.ValueChangedFcn = createCallbackFcn(app, @B_TextArea_theta1ValueChanged, true);
            app.B_TextArea_theta2 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_theta2.ValueChangedFcn = createCallbackFcn(app, @B_TextArea_theta2ValueChanged, true);
            app.B_TextArea_theta3 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_theta3.ValueChangedFcn = createCallbackFcn(app, @B_TextArea_theta3ValueChanged, true);
            app.B_TextArea_theta4 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_theta4.ValueChangedFcn = createCallbackFcn(app, @B_TextArea_theta4ValueChanged, true);
            app.B_TextArea_theta5 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_theta5.ValueChangedFcn = createCallbackFcn(app, @B_TextArea_theta5ValueChanged, true);
            app.B_TextArea_theta6 = uieditfield(app.DHTablePanel_2, 'numeric');
            app.B_TextArea_theta6.ValueChangedFcn = createCallbackFcn(app, @B_TextArea_theta6ValueChanged, true);
            app.B_DELETEButton = uibutton(app.DHTablePanel_2, 'push');
            app.B_DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @B_DELETEButtonPushed, true);
            app.B_DELETEButton.Text = 'DELETE';
            app.B_EnableConrolCheckBox = uicheckbox(app.DHTablePanel_2);
            app.B_EnableConrolCheckBox.ValueChangedFcn = createCallbackFcn(app, @B_EnableConrolCheckBoxValueChanged, true);
            app.B_EnableConrolCheckBox.Text = 'Enable Conrol';
            app.Hyperlink = uihyperlink(app.DHTablePanel_2);
            app.Hyperlink.URL = 'https://robodk.com/robot/KUKA/KR-8-R1420-arc-HW';
            app.Hyperlink.Text = 'Robot_Link';
            app.B_UploadButton_2 = uibutton(app.DHTablePanel_2, 'push');
            app.B_UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @B_UploadButton_2Pushed, true);
            app.B_UploadButton_2.Text = 'Inverse Kinematics';
            app.SliderForAxisPanel_2 = uipanel(app.KUKA_KR8_R1420Tab);
            app.SliderForAxisPanel_2.Title = 'Slider For Axis';
            app.B_Theta2_Slider2 = uislider(app.SliderForAxisPanel_2);
            app.B_Theta2_Slider2.Limits = [-95 155];
            app.B_Theta2_Slider2.ValueChangingFcn = createCallbackFcn(app, @B_Theta2_Slider2ValueChanging, true);
            app.B_Theta1_Slider1 = uislider(app.SliderForAxisPanel_2);
            app.B_Theta1_Slider1.Limits = [-170 170];
            app.B_Theta1_Slider1.ValueChangingFcn = createCallbackFcn(app, @B_Theta1_Slider1ValueChanging, true);
            app.B_Theta6_Slider6 = uislider(app.SliderForAxisPanel_2);
            app.B_Theta6_Slider6.Limits = [-350 350];
            app.B_Theta6_Slider6.ValueChangingFcn = createCallbackFcn(app, @B_Theta6_Slider6ValueChanging, true);
            app.B_Theta3_Slider3 = uislider(app.SliderForAxisPanel_2);
            app.B_Theta3_Slider3.Limits = [-210 90];
            app.B_Theta3_Slider3.ValueChangingFcn = createCallbackFcn(app, @B_Theta3_Slider3ValueChanging, true);
            app.B_Theta5_Slider5 = uislider(app.SliderForAxisPanel_2);
            app.B_Theta5_Slider5.Limits = [-115 140];
            app.B_Theta5_Slider5.MajorTicks = [-115 -64 -13 38 89 140];
            app.B_Theta5_Slider5.MajorTickLabels = {'-115,', '-64,', '-13,', '38,', '89,', '140'};
            app.B_Theta5_Slider5.ValueChangingFcn = createCallbackFcn(app, @B_Theta5_Slider5ValueChanging, true);
            app.B_Theta4_Slider4 = uislider(app.SliderForAxisPanel_2);
            app.B_Theta4_Slider4.Limits = [-165 165];
            app.B_Theta4_Slider4.ValueChangingFcn = createCallbackFcn(app, @B_Theta4_Slider4ValueChanging, true);
            app.AxisLabel = uilabel(app.SliderForAxisPanel_2);
            app.AxisLabel.Text = '1. Axis';
            app.AxisLabel_4 = uilabel(app.SliderForAxisPanel_2);
            app.AxisLabel_4.Text = '4.Axis';
            app.AxisLabel_5 = uilabel(app.SliderForAxisPanel_2);
            app.AxisLabel_5.Text = '5. Axis';
            app.AxisLabel_2 = uilabel(app.SliderForAxisPanel_2);
            app.AxisLabel_2.Text = '2. Axis';
            app.AxisLabel_6 = uilabel(app.SliderForAxisPanel_2);
            app.AxisLabel_6.Text = '6. Axis';
            app.AxisLabel_3 = uilabel(app.SliderForAxisPanel_2);
            app.AxisLabel_3.Text = '3. Axis';
            app.InverseKinematicsPanel = uipanel(app.KUKA_KR8_R1420Tab);
            app.InverseKinematicsPanel.Title = 'Inverse Kinematics';
            app.B_EditField_A_Roll = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.B_EditField_A_Roll.Value = 25;
            app.B_EditField_B_Pitch = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.B_EditField_B_Pitch.Value = -17;
            app.B_EditField_C_Yaw = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.B_EditField_C_Yaw.Value = 42;
            app.RollLabel = uilabel(app.InverseKinematicsPanel);
            app.RollLabel.Text = 'Roll';
            app.PitchLabel = uilabel(app.InverseKinematicsPanel);
            app.PitchLabel.Text = 'Pitch';
            app.YawLabel = uilabel(app.InverseKinematicsPanel);
            app.YawLabel.Text = 'Yaw';
            app.B_ZKonumuEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.B_ZKonumuEditField.Value = 42;
            app.B_XKonumuEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.B_XKonumuEditField.Value = 25;
            app.B_YKonumuEditField = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.B_YKonumuEditField.Value = -17;
            app.TargetXLabel = uilabel(app.InverseKinematicsPanel);
            app.TargetXLabel.Text = 'Target X';
            app.TargetYLabel = uilabel(app.InverseKinematicsPanel);
            app.TargetYLabel.Text = 'Target Y';
            app.TargetZLabel = uilabel(app.InverseKinematicsPanel);
            app.TargetZLabel.Text = 'Target Z';
            app.B_TersHesaplaButtonGroup = uibuttongroup(app.InverseKinematicsPanel);
            app.B_TersHesaplaButtonGroup.Title = 'Inverse Calculate';
            app.B_FabrikButton = uiradiobutton(app.B_TersHesaplaButtonGroup);
            app.B_FabrikButton.Text = 'Fabrik';
            app.B_FabrikButton.Value = true;
            app.B_NewtonRapsonButton = uiradiobutton(app.B_TersHesaplaButtonGroup);
            app.B_NewtonRapsonButton.Text = 'Newton Rapson';
            app.B_Ters_Kinematik = uitable(app.InverseKinematicsPanel);
            app.B_Ters_Kinematik.ColumnName = '';
            app.B_Ters_Kinematik.RowName = {};
            app.InverseKinematicLabel = uilabel(app.InverseKinematicsPanel);
            app.InverseKinematicLabel.Text = 'Inverse Kinematic';
            app.TablesGroupPanel = uipanel(app.KUKA_KR8_R1420Tab);
            app.TablesGroupPanel.Title = 'Tables Group';
            app.B_UITable_Oryantasyon = uitable(app.TablesGroupPanel);
            app.B_UITable_Oryantasyon.ColumnName = {'X'; 'Y'; 'Z'};
            app.B_UITable_Oryantasyon.RowName = {};
            app.B_UITable2_Location = uitable(app.TablesGroupPanel);
            app.B_UITable2_Location.ColumnName = {'Location'};
            app.B_UITable2_Location.RowName = {};
            app.OrientationLabel_2 = uilabel(app.TablesGroupPanel);
            app.OrientationLabel_2.Text = 'Orientation';
            app.PositionLabel_2 = uilabel(app.TablesGroupPanel);
            app.PositionLabel_2.Text = 'Position';
            app.B_UITable_ForwardKinematic = uitable(app.TablesGroupPanel);
            app.B_UITable_ForwardKinematic.ColumnName = {'α'; 'a'; 'd'; 'θ'};
            app.B_UITable_ForwardKinematic.RowName = {};
            app.ForwardKinematicsLabel = uilabel(app.TablesGroupPanel);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            app.B_UITable_RollPitchYaw = uitable(app.TablesGroupPanel);
            app.B_UITable_RollPitchYaw.ColumnName = {'Roll X'; 'Pitch Y'; 'Yaw Z'};
            app.B_UITable_RollPitchYaw.RowName = {};
            app.RollPitchYawOrientationLabel = uilabel(app.TablesGroupPanel);
            app.RollPitchYawOrientationLabel.Text = 'RollPitchYaw Orientation';
            app.ASetiHesaplamaTab = uitab(app.TabGroup);
            app.ASetiHesaplamaTab.Title = 'Açı Seti Hesaplama';
            app.C_ButtonGroup = uibuttongroup(app.ASetiHesaplamaTab);
            app.C_ButtonGroup.Title = 'Angle sets';
            app.C_Hareketli_XYZ = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_XYZ.Text = 'Euler (XYZ)';
            app.C_Hareketli_XYZ.Value = true;
            app.C_Hareketli_YXZ = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_YXZ.Text = 'Euler (YXZ)';
            app.C_Hareketli_XZY = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_XZY.Text = 'Euler (XZY)';
            app.C_Hareketli_YZX = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_YZX.Text = 'Euler (YZX)';
            app.C_Hareketli_ZXY = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_ZXY.Text = 'Euler (ZXY)';
            app.C_Hareketli_ZYX = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_ZYX.Text = 'Euler (ZYX)';
            app.C_Hareketli_XYX = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_XYX.Text = 'Euler (XYX)';
            app.C_Hareketli_XZX = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_XZX.Text = 'Euler (XZX)';
            app.C_Hareketli_YXY = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_YXY.Text = 'Euler (YXY)';
            app.C_Hareketli_YZY = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_YZY.Text = 'Euler (YZY)';
            app.C_Hareketli_ZXZ = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_ZXZ.Text = 'Euler (ZXZ)';
            app.C_Hareketli_ZYZ = uiradiobutton(app.C_ButtonGroup);
            app.C_Hareketli_ZYZ.Text = 'Euler (ZYZ)';
            app.C_SabitRPY_XYZ = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_XYZ.Text = 'RollPitchYaw(XYZ)';
            app.C_SabitRPY_YXZ = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_YXZ.Text = 'RollPitchYaw(YXZ)';
            app.C_SabitRPY_XZY = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_XZY.Text = 'RollPitchYaw(XZY)';
            app.C_SabitRPY_YZX = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_YZX.Text = 'RollPitchYaw(YZX)';
            app.C_SabitRPY_ZXY = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_ZXY.Text = 'RollPitchYaw(ZXY)';
            app.C_SabitRPY_ZYX = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_ZYX.Text = 'RollPitchYaw(ZYX)';
            app.C_SabitRPY_XYX = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_XYX.Text = 'RollPitchYaw(XYX)';
            app.C_SabitRPY_XZX = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_XZX.Text = 'RollPitchYaw(XZX)';
            app.C_SabitRPY_YXY = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_YXY.Text = 'RollPitchYaw(YXY)';
            app.C_SabitRPY_YZY = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_YZY.Text = 'RollPitchYaw(YZY)';
            app.C_SabitRPY_ZXZ = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_ZXZ.Text = 'RollPitchYaw(ZXZ)';
            app.C_SabitRPY_ZYZ = uiradiobutton(app.C_ButtonGroup);
            app.C_SabitRPY_ZYZ.Text = 'RollPitchYaw(ZYZ)';
            app.RollPitchYawLabel = uilabel(app.C_ButtonGroup);
            app.RollPitchYawLabel.Text = 'RollPitchYaw';
            app.EULERLabel = uilabel(app.C_ButtonGroup);
            app.EULERLabel.Text = 'EULER';
            app.CalculateRotationPanel = uipanel(app.ASetiHesaplamaTab);
            app.CalculateRotationPanel.Title = 'Calculate Rotation';
            app.A = uieditfield(app.CalculateRotationPanel, 'numeric');
            app.A.Value = 25;
            app.B = uieditfield(app.CalculateRotationPanel, 'numeric');
            app.B.Value = -17;
            app.G = uieditfield(app.CalculateRotationPanel, 'numeric');
            app.G.Value = 42;
            app.AlphaLabel = uilabel(app.CalculateRotationPanel);
            app.AlphaLabel.Text = 'Alpha';
            app.BetaLabel = uilabel(app.CalculateRotationPanel);
            app.BetaLabel.Text = 'Beta';
            app.GamaLabel = uilabel(app.CalculateRotationPanel);
            app.GamaLabel.Text = 'Gama';
            app.C_Rotasyon_Button = uibutton(app.CalculateRotationPanel, 'push');
            app.C_Rotasyon_Button.ButtonPushedFcn = createCallbackFcn(app, @C_Rotasyon_ButtonPushed, true);
            app.C_Rotasyon_Button.Text = 'Calculate Rotation';
            app.C_UITable_Rotasyon = uitable(app.CalculateRotationPanel);
            app.C_UITable_Rotasyon.ColumnName = {'Rotation'};
            app.C_UITable_Rotasyon.RowName = {};
            app.AngleValueLabel = uilabel(app.CalculateRotationPanel);
            app.AngleValueLabel.Text = 'Angle Value';
            app.CalculateAnglePanel = uipanel(app.ASetiHesaplamaTab);
            app.CalculateAnglePanel.Title = 'Calculate Angle';
            app.Label_6 = uilabel(app.CalculateAnglePanel);
            app.Label_6.Text = '';
            app.G1 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.G1.Value = -0.2924;
            app.B1 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.B1.Value = -0.6399;
            app.A1 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.A1.Value = 0.7107;
            app.A2 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.A2.Value = 0.5146;
            app.B2 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.B2.Value = 0.7562;
            app.G2 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.G2.Value = -0.4042;
            app.G3 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.G3.Value = 0.8667;
            app.B3 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.B3.Value = 0.1368;
            app.A3 = uieditfield(app.CalculateAnglePanel, 'numeric');
            app.A3.Value = 0.4797;
            app.RotationMatrixLabel = uilabel(app.CalculateAnglePanel);
            app.RotationMatrixLabel.Text = 'Rotation Matrix';
            app.C_Aci_Button = uibutton(app.CalculateAnglePanel, 'push');
            app.C_Aci_Button.ButtonPushedFcn = createCallbackFcn(app, @C_Aci_ButtonPushed, true);
            app.C_Aci_Button.Text = 'Angle,K, Theta Calculate';
            app.C_UITable_2 = uitable(app.CalculateAnglePanel);
            app.C_UITable_2.ColumnName = {'K'; 'θ'; 'AlphaBetaGamma'};
            app.C_UITable_2.RowName = {};
            app.Alpha_Cikti = uieditfield(app.ASetiHesaplamaTab, 'numeric');
            app.Alpha_Cikti.Visible = 'off';
            app.Beta_Cikti = uieditfield(app.ASetiHesaplamaTab, 'numeric');
            app.Beta_Cikti.Visible = 'off';
            app.Gama_Cikti = uieditfield(app.ASetiHesaplamaTab, 'numeric');
            app.Gama_Cikti.Visible = 'off';
            app.AngleValueLabel_2 = uilabel(app.ASetiHesaplamaTab);
            app.AngleValueLabel_2.Visible = 'off';
            app.AngleValueLabel_2.Text = 'Angle Value';
            app.AlphaLabel_2 = uilabel(app.ASetiHesaplamaTab);
            app.AlphaLabel_2.Visible = 'off';
            app.AlphaLabel_2.Text = 'Alpha';
            app.BetaLabel_2 = uilabel(app.ASetiHesaplamaTab);
            app.BetaLabel_2.Visible = 'off';
            app.BetaLabel_2.Text = 'Beta';
            app.GammaLabel = uilabel(app.ASetiHesaplamaTab);
            app.GammaLabel.Visible = 'off';
            app.GammaLabel.Text = 'Gamma';
            app.C_Teta_Text = uieditfield(app.ASetiHesaplamaTab, 'numeric');
            app.C_Teta_Text.Visible = 'off';
            app.C_K1 = uieditfield(app.ASetiHesaplamaTab, 'numeric');
            app.C_K1.Visible = 'off';
            app.C_K2 = uieditfield(app.ASetiHesaplamaTab, 'numeric');
            app.C_K2.Visible = 'off';
            app.C_K3 = uieditfield(app.ASetiHesaplamaTab, 'numeric');
            app.C_K3.Visible = 'off';
            app.Label_7 = uilabel(app.ASetiHesaplamaTab);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = 'θ';
            app.KLabel = uilabel(app.ASetiHesaplamaTab);
            app.KLabel.Visible = 'off';
            app.KLabel.Text = 'K';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_Kinematigi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end